classdef PSFCorrManual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        STACKPSFButton                 matlab.ui.control.Button
        AxialRangeumEditField          matlab.ui.control.NumericEditField
        AxialRangeumEditFieldLabel     matlab.ui.control.Label
        ScaleFactorEditField           matlab.ui.control.NumericEditField
        FineTuneResizingScaleLabel     matlab.ui.control.Label
        MakefoldersButton              matlab.ui.control.Button
        MediumDropDown                 matlab.ui.control.DropDown
        MediumDropDownLabel            matlab.ui.control.Label
        FluorColorDropDown             matlab.ui.control.DropDown
        FluorColorDropDownLabel        matlab.ui.control.Label
        AlignManuallyButton            matlab.ui.control.Button
        ShiftRawButton                 matlab.ui.control.Button
        STARTFINEALIGNINGButton        matlab.ui.control.Button
        FineTuneButton                 matlab.ui.control.Button
        GetCentroidsButton             matlab.ui.control.Button
        GetAligningDisplacementButton  matlab.ui.control.Button
        StatusLabel                    matlab.ui.control.Label
        ShowROIsButton                 matlab.ui.control.Button
        ExportVarsButton               matlab.ui.control.Button
        SavePSFLabel                   matlab.ui.control.Label
        SaveFolderButton               matlab.ui.control.Button
        SimExpFrameNoLabel             matlab.ui.control.Label
        STARTROUGHALIGNINGButton       matlab.ui.control.Button
        OpenExperimentalPSFFolderButton  matlab.ui.control.Button
        OpenSimulatedPSFFolderButton   matlab.ui.control.Button
        ForwardButton                  matlab.ui.control.Button
        BackwardButton                 matlab.ui.control.Button
        GetExpROIsButton               matlab.ui.control.Button
        GetSimROIsButton               matlab.ui.control.Button
        ExperimentalPSFLabel           matlab.ui.control.Label
        SimulatedPSFLabel              matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        initialized =0;
        initialized2 =0;
        selpath = '.'; % Open directory
        selpath2 = '.'; % Open directory
        savepath = '.';
        
        tifnum = 0;
        tifnames = [];
        tifind = [];
        
        tifnum2 = 0;
        tifnames2 = [];
        tifind2 = [];
        
        SimExpInd = [0,0];
        
        preimg = [];
        preimg2 = [];
        
        ROI = [];
        ROI2 = [];
        ROIboth = [];
        
        alignind = [];
        dx1 = []; dy1 = [];
        dx2 = []; dy2 = [];
        dx3 = []; dy3 = [];
        DXY = [];
        
        dispApp = [];
        showFeatureApp = [];
        manualAli = [];
        
        centpos_re = [];
        dcentpos_re = [];
        dcentpos = [];

        saverough = '';
        savefine = '';
    end
    
    methods (Access = private)
        
        function [posx,posy] = imcentroid(~,iminput)
            [row,col] = size(iminput);
            [X,Y] = meshgrid(1:1:col,1:1:row);
            posx = sum(X.*iminput,'all')/sum(iminput,'all');
            posy = sum(Y.*iminput,'all')/sum(iminput,'all');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'tight');
            axis(app.UIAxes_2,'tight');
        end

        % Button pushed function: OpenSimulatedPSFFolderButton
        function OpenSimulatedPSFFolderButtonPushed(app, event)
            paths = {'Z:\Xuanwen\FLFMuf\ExpData',app.selpath};
            [app.selpath,subfolder] = getDir(paths{app.initialized+1},'mat');
            try
                app.tifnum = length(subfolder);
                app.tifnames = string({subfolder(:).name}');
                app.tifind = str2double(string(regexp(app.tifnames,'\d*','Match')));
                app.SimulatedPSFLabel.Text = app.selpath;
                app.SimExpInd(1) = 50000;
                app.SimExpFrameNoLabel.Text = num2str(app.SimExpInd);
                load([char(app.selpath),'/',char(app.tifnames(app.tifind==50000))],'PSF');
                app.preimg = abs(PSF).^2;
                imagesc(app.preimg,'Parent',app.UIAxes);
            catch ME
                app.StatusLabel.Text = ['[Error]',ME.message];
                drawnow;
            end
            app.initialized = 1;
        end

        % Button pushed function: OpenExperimentalPSFFolderButton
        function OpenExperimentalPSFFolderButtonPushed(app, event)
            paths = {'Z:/Xuanwen/FLFMuf/ExpData',app.selpath2};
            [app.selpath2,subfolder] = getDir(paths{app.initialized2+1},'tif');
            try
                app.tifnum2 = length(subfolder);
                app.tifnames2 = string({subfolder(:).name}');
                app.tifind2 = str2double(string(regexp(app.tifnames2,'\d*','Match')));
                app.ExperimentalPSFLabel.Text = app.selpath2;
                app.SimExpInd(2) = 50000;
                app.SimExpFrameNoLabel.Text = num2str(app.SimExpInd);
                app.preimg2 = double(imread([char(app.selpath2),'/',char(app.tifnames2(app.tifind2==50000))]));
%                 app.preimg2 = app.preimg2/max(app.preimg2(:));
                imagesc(double(app.preimg2),'Parent',app.UIAxes_2);
            catch
                try
                    disp([app.selpath2,'/',subfolder(1).name])
                    app.preimg2 = double(imread([app.selpath2,'/',subfolder(1).name]));
                    app.SimExpInd(2) = 50000;
                    app.SimExpFrameNoLabel.Text = num2str(app.SimExpInd);
                    imagesc(double(app.preimg2),'Parent',app.UIAxes_2);
                catch ME
                    app.StatusLabel.Text = ['[Error]',ME.message];
                    drawnow;
                end
            end
            app.initialized2 = 1;
        end

        % Button pushed function: SaveFolderButton
        function SaveFolderButtonPushed(app, event)
            paths = {'Z:/uanwen/FLFMuf/ExpData',app.selpath};
            [app.savepath,~] = getDir(paths{app.initialized+1});
            app.SavePSFLabel.Text = app.savepath;
            app.initialized = 1;
        end

        % Button pushed function: BackwardButton
        function BackwardButtonPushed(app, event)
            try
                app.BackwardButton.Enable = true;
                app.ForwardButton.Enable = true;
                app.SimExpInd = app.SimExpInd-1*100;
                app.SimExpFrameNoLabel.Text = num2str(app.SimExpInd);
                %                 if app.SimExpInd >= max(app.tifind(1),app.tifind2(1)) && app.SimExpInd <= min(app.tifind(end),app.tifind2(end))
                
                load([char(app.selpath),'/',char(app.tifnames(app.tifind==app.SimExpInd(1)))],'PSF');
                app.preimg = PSF;
                app.preimg = abs(app.preimg).^2;
                app.preimg = app.preimg/max(app.preimg(:));
                imagesc(app.preimg,'Parent',app.UIAxes);
                
                try
                    app.preimg2 = double(imread([char(app.selpath2),'/',char(app.tifnames2(app.tifind2==app.SimExpInd(2)))]));
                catch
                    app.preimg2 = double(imread([char(app.selpath2),'/',dir([app.selpath2,'/*.tif']).name]));
%                     app.BackwardButton.Enable = false;
                    app.SimExpInd(2) = app.SimExpInd(2)+1*100;
                    app.SimExpFrameNoLabel.Text = num2str(app.SimExpInd);
                end
                app.preimg2 = app.preimg2/max(app.preimg2(:));
                imagesc(app.preimg2,'Parent',app.UIAxes_2);
                %                 end
            catch
                app.BackwardButton.Enable = false;
                app.SimExpInd = app.SimExpInd+1*100;
                app.SimExpFrameNoLabel.Text = num2str(app.SimExpInd);
            end
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            try
                app.BackwardButton.Enable = true;
                app.ForwardButton.Enable = true;
                app.SimExpInd = app.SimExpInd+1*100;
                app.SimExpFrameNoLabel.Text = num2str(app.SimExpInd);
                
                load([char(app.selpath),'/',char(app.tifnames(app.tifind==app.SimExpInd(1)))],'PSF');
                app.preimg = PSF;
                app.preimg = abs(app.preimg).^2;
                app.preimg = app.preimg/max(app.preimg(:));
                imagesc(app.preimg,'Parent',app.UIAxes);
                
                try
                    app.preimg2 = double(imread([char(app.selpath2),'/',char(app.tifnames2(app.tifind2==app.SimExpInd(2)))]));
                catch
                    app.preimg2 = double(imread([char(app.selpath2),'/',dir([app.selpath2,'/*.tif']).name]));
%                     app.ForwardButton.Enable = false;
                    app.SimExpInd(2) = app.SimExpInd(2)-1*100;
                    app.SimExpFrameNoLabel.Text = num2str(app.SimExpInd);
                end
                app.preimg2 = app.preimg2/max(app.preimg2(:));
                imagesc(app.preimg2,'Parent',app.UIAxes_2);
            catch
                app.ForwardButton.Enable = false;
                app.SimExpInd = app.SimExpInd-1*100;
                app.SimExpFrameNoLabel.Text = num2str(app.SimExpInd);
            end
        end

        % Button pushed function: GetSimROIsButton
        function GetSimROIsButtonPushed(app, event)
            try
                figure(1),imagesc(app.preimg);
                [posx,posy] = ginput(3);
                
                simroi = 25;
                sq1 = app.preimg(posy(1)-simroi:posy(1)+simroi,posx(1)-simroi:posx(1)+simroi);
                sq2 = app.preimg(posy(2)-simroi:posy(2)+simroi,posx(2)-simroi:posx(2)+simroi);
                sq3 = app.preimg(posy(3)-simroi:posy(3)+simroi,posx(3)-simroi:posx(3)+simroi);
                [maxrow1,maxcol1] = getMax(sq1,simroi+1,simroi+1);
                [maxrow2,maxcol2] = getMax(sq2,simroi+1,simroi+1);
                [maxrow3,maxcol3] = getMax(sq3,simroi+1,simroi+1);
                maxrow1 = round(maxrow1 + posy(1)-simroi-1);
                maxcol1 = round(maxcol1 + posx(1)-simroi-1);
                maxrow2 = round(maxrow2 + posy(2)-simroi-1);
                maxcol2 = round(maxcol2 + posx(2)-simroi-1);
                maxrow3 = round(maxrow3 + posy(3)-simroi-1);
                maxcol3 = round(maxcol3 + posx(3)-simroi-1);
                
                expandfactor = 2.5;
                app.ROI = round([maxrow1-simroi*expandfactor,maxcol1-simroi*expandfactor,maxrow1+simroi*expandfactor,maxcol1+simroi*expandfactor;
                                 maxrow2-simroi*expandfactor,maxcol2-simroi*expandfactor,maxrow2+simroi*expandfactor,maxcol2+simroi*expandfactor;
                                 maxrow3-simroi*expandfactor,maxcol3-simroi*expandfactor,maxrow3+simroi*expandfactor,maxcol3+simroi*expandfactor]);
                try
                    app.ROIboth = round([min(app.ROI(:,1),app.ROI2(:,1)),...
                                         min(app.ROI(:,2),app.ROI2(:,2)),...
                                         max(app.ROI(:,3),app.ROI2(:,3)),...
                                         max(app.ROI(:,4),app.ROI2(:,4))]);
                catch
                    app.ROIboth = app.ROI;
                end
            catch
            end
        end

        % Button pushed function: GetExpROIsButton
        function GetExpROIsButtonPushed(app, event)
            try
                figure(2),imagesc(app.preimg2);
                [posx,posy] = ginput(3);
                
                exproi = 25;
                sq1 = app.preimg2(posy(1)-exproi:posy(1)+exproi,posx(1)-exproi:posx(1)+exproi);
                sq2 = app.preimg2(posy(2)-exproi:posy(2)+exproi,posx(2)-exproi:posx(2)+exproi);
                sq3 = app.preimg2(posy(3)-exproi:posy(3)+exproi,posx(3)-exproi:posx(3)+exproi);
                [maxrow1,maxcol1] = getMax(sq1,exproi+1,exproi+1);
                [maxrow2,maxcol2] = getMax(sq2,exproi+1,exproi+1);
                [maxrow3,maxcol3] = getMax(sq3,exproi+1,exproi+1);
                maxrow1 = round(maxrow1 + posy(1)-exproi-1);
                maxcol1 = round(maxcol1 + posx(1)-exproi-1);
                maxrow2 = round(maxrow2 + posy(2)-exproi-1);
                maxcol2 = round(maxcol2 + posx(2)-exproi-1);
                maxrow3 = round(maxrow3 + posy(3)-exproi-1);
                maxcol3 = round(maxcol3 + posx(3)-exproi-1);
                
                expandfactor = 2.5;
                app.ROI2 = round([maxrow1-exproi*expandfactor,maxcol1-exproi*expandfactor,maxrow1+exproi*expandfactor,maxcol1+exproi*expandfactor;
                                  maxrow2-exproi*expandfactor,maxcol2-exproi*expandfactor,maxrow2+exproi*expandfactor,maxcol2+exproi*expandfactor;
                                  maxrow3-exproi*expandfactor,maxcol3-exproi*expandfactor,maxrow3+exproi*expandfactor,maxcol3+exproi*expandfactor]);
                try
                    app.ROIboth = round([min(app.ROI(:,1),app.ROI2(:,1)),...
                                         min(app.ROI(:,2),app.ROI2(:,2)),...
                                         max(app.ROI(:,3),app.ROI2(:,3)),...
                                         max(app.ROI(:,4),app.ROI2(:,4))]);
                catch
                    app.ROIboth = app.ROI;
                end
            catch
            end
            
        end

        % Button pushed function: ShowROIsButton
        function ShowROIsButtonPushed(app, event)
%             disp(app.ROI)
%             disp(app.ROI2)
%             
%             app.ROIboth = round([min(app.ROI(:,1),app.ROI2(:,1)),...
%                                  min(app.ROI(:,2),app.ROI2(:,2)),...
%                                  max(app.ROI(:,3),app.ROI2(:,3)),...
%                                  max(app.ROI(:,4),app.ROI2(:,4))]);
            disp(app.ROIboth)
            app.preimg(app.ROIboth(1,1)-2:app.ROIboth(1,1)+2,app.ROIboth(1,2)-2:app.ROIboth(1,4)+2) = max(app.preimg(:));
            app.preimg(app.ROIboth(2,1)-2:app.ROIboth(2,1)+2,app.ROIboth(2,2)-2:app.ROIboth(2,4)+2) = max(app.preimg(:));
            app.preimg(app.ROIboth(3,1)-2:app.ROIboth(3,1)+2,app.ROIboth(3,2)-2:app.ROIboth(3,4)+2) = max(app.preimg(:));
            
            app.preimg(app.ROIboth(1,3)-2:app.ROIboth(1,3)+2,app.ROIboth(1,2)-2:app.ROIboth(1,4)+2) = max(app.preimg(:));
            app.preimg(app.ROIboth(2,3)-2:app.ROIboth(2,3)+2,app.ROIboth(2,2)-2:app.ROIboth(2,4)+2) = max(app.preimg(:));
            app.preimg(app.ROIboth(3,3)-2:app.ROIboth(3,3)+2,app.ROIboth(3,2)-2:app.ROIboth(3,4)+2) = max(app.preimg(:));
            
            app.preimg(app.ROIboth(1,1)-2:app.ROIboth(1,3)+2,app.ROIboth(1,2)-2:app.ROIboth(1,2)+2) = max(app.preimg(:));
            app.preimg(app.ROIboth(2,1)-2:app.ROIboth(2,3)+2,app.ROIboth(2,2)-2:app.ROIboth(2,2)+2) = max(app.preimg(:));
            app.preimg(app.ROIboth(3,1)-2:app.ROIboth(3,3)+2,app.ROIboth(3,2)-2:app.ROIboth(3,2)+2) = max(app.preimg(:));
            
            app.preimg(app.ROIboth(1,1)-2:app.ROIboth(1,3)+2,app.ROIboth(1,4)-2:app.ROIboth(1,4)+2) = max(app.preimg(:));
            app.preimg(app.ROIboth(2,1)-2:app.ROIboth(2,3)+2,app.ROIboth(2,4)-2:app.ROIboth(2,4)+2) = max(app.preimg(:));
            app.preimg(app.ROIboth(3,1)-2:app.ROIboth(3,3)+2,app.ROIboth(3,4)-2:app.ROIboth(3,4)+2) = max(app.preimg(:));
            % ===========================================================================
            app.preimg2(app.ROIboth(1,1)-2:app.ROIboth(1,1)+2,app.ROIboth(1,2)-2:app.ROIboth(1,4)+2) = max(app.preimg2(:));
            app.preimg2(app.ROIboth(2,1)-2:app.ROIboth(2,1)+2,app.ROIboth(2,2)-2:app.ROIboth(2,4)+2) = max(app.preimg2(:));
            app.preimg2(app.ROIboth(3,1)-2:app.ROIboth(3,1)+2,app.ROIboth(3,2)-2:app.ROIboth(3,4)+2) = max(app.preimg2(:));
            
            app.preimg2(app.ROIboth(1,3)-2:app.ROIboth(1,3)+2,app.ROIboth(1,2)-2:app.ROIboth(1,4)+2) = max(app.preimg2(:));
            app.preimg2(app.ROIboth(2,3)-2:app.ROIboth(2,3)+2,app.ROIboth(2,2)-2:app.ROIboth(2,4)+2) = max(app.preimg2(:));
            app.preimg2(app.ROIboth(3,3)-2:app.ROIboth(3,3)+2,app.ROIboth(3,2)-2:app.ROIboth(3,4)+2) = max(app.preimg2(:));
            
            app.preimg2(app.ROIboth(1,1)-2:app.ROIboth(1,3)+2,app.ROIboth(1,2)-2:app.ROIboth(1,2)+2) = max(app.preimg2(:));
            app.preimg2(app.ROIboth(2,1)-2:app.ROIboth(2,3)+2,app.ROIboth(2,2)-2:app.ROIboth(2,2)+2) = max(app.preimg2(:));
            app.preimg2(app.ROIboth(3,1)-2:app.ROIboth(3,3)+2,app.ROIboth(3,2)-2:app.ROIboth(3,2)+2) = max(app.preimg2(:));
            
            app.preimg2(app.ROIboth(1,1)-2:app.ROIboth(1,3)+2,app.ROIboth(1,4)-2:app.ROIboth(1,4)+2) = max(app.preimg2(:));
            app.preimg2(app.ROIboth(2,1)-2:app.ROIboth(2,3)+2,app.ROIboth(2,4)-2:app.ROIboth(2,4)+2) = max(app.preimg2(:));
            app.preimg2(app.ROIboth(3,1)-2:app.ROIboth(3,3)+2,app.ROIboth(3,4)-2:app.ROIboth(3,4)+2) = max(app.preimg2(:));
            % ===========================================================================
            imagesc(app.preimg,'Parent',app.UIAxes);
            imagesc(app.preimg2,'Parent',app.UIAxes_2);
        end

        % Button pushed function: ExportVarsButton
        function ExportVarsButtonPushed(app, event)
            labels = {  'Save sim. PSF opened directory to variable named:',...
                        'Save exp. PSF opened directory to variable named:',...
                        'Save saving directory to variable named:',...
                        'Save sim. tif numbers to variable named:',...
                        'Save sim. tif filenames to variable named:',...
                        'Save sim. tif index to variable named:',...
                        'Save exp. tif numbers to variable named:',...
                        'Save exp. tif filenames to variable named:',...
                        'Save exp. tif index to variable named:',...
                        'Save sim. & exp. indeces to variable named:',...
                        'Save preview sim. image to variable named:',...
                        'Save preview exp. image to variable named:',...
                        'Save sim. ROI to variable named:',...
                        'Save exp. ROI to variable named:',...
                        'Save integrated ROI to variable named:',...
                        'Save indeces to align to variable named:',...
                        'Save Offsets to variable named:'};
            vars = {'selpath','selpath2','savepath','tifnum','tifnames','tifind','tifnum2','tifnames2','tifind2',...
                'SimExpInd','preimg','preimg2','ROI','ROI2','ROIboth','alignind','Offset'};
            xyOffset = [app.dy1,app.dx1,app.dy2,app.dx2,app.dy3,app.dx3];
            values = {app.selpath,app.selpath2,app.savepath,app.tifnum,app.tifnames,app.tifind,app.tifnum2,...
                app.tifnames2,app.tifind2,app.SimExpInd,app.preimg,app.preimg2,app.ROI,app.ROI2,app.ROIboth,app.alignind,xyOffset};
            export2wsdlg(labels,vars,values);
        end

        % Button pushed function: STARTROUGHALIGNINGButton
        function STARTROUGHALIGNINGButtonPushed(app, event)
            app.StatusLabel.Text = 'Aligning...';
            app.StatusLabel.BackgroundColor = [0.87,0.94,0.30];
            drawnow;
            try
                app.STARTROUGHALIGNINGButton.Enable = false;
                for ii = app.tifind'
                    
                    load([char(app.selpath),'/',char(app.tifnames(app.tifind==ii))],'PSF');
                    try
                        disp([app.alignind(app.alignind==ii),app.dy1(app.alignind==ii),app.dx1(app.alignind==ii)])

                        PSF(app.ROIboth(1,1):app.ROIboth(1,3),app.ROIboth(1,2):app.ROIboth(1,4)) = ...
                            circshift(PSF(app.ROIboth(1,1):app.ROIboth(1,3),app.ROIboth(1,2):app.ROIboth(1,4)),...
                            [app.dy1(app.alignind==ii),app.dx1(app.alignind==ii)]);
                        PSF(app.ROIboth(2,1):app.ROIboth(2,3),app.ROIboth(2,2):app.ROIboth(2,4)) = ...
                            circshift(PSF(app.ROIboth(2,1):app.ROIboth(2,3),app.ROIboth(2,2):app.ROIboth(2,4)),...
                            [app.dy2(app.alignind==ii),app.dx2(app.alignind==ii)]);
                        PSF(app.ROIboth(3,1):app.ROIboth(3,3),app.ROIboth(3,2):app.ROIboth(3,4)) = ...
                            circshift(PSF(app.ROIboth(3,1):app.ROIboth(3,3),app.ROIboth(3,2):app.ROIboth(3,4)),...
                            [app.dy3(app.alignind==ii),app.dx3(app.alignind==ii)]);
                    catch
                        app.StatusLabel.Text = ['Aligning Excluded! [',num2str(ii),']'];
                        app.StatusLabel.BackgroundColor = [0.47,0.82,0.82];
                    end
                    %                     PSF = simimg;
                    save([char([app.savepath,'/',app.saverough]),'/',char(app.tifnames(app.tifind==ii))],'PSF')
                    app.StatusLabel.Text = ['Aligning... [',num2str(ii),']'];
                    drawnow;
                end
                app.StatusLabel.Text = 'Done!';
                app.StatusLabel.BackgroundColor = [0.47,0.82,0.82];
                drawnow;
            catch ME
                MEstack = ME.stack(3);
                %                 disp(MEstack.line)
                app.StatusLabel.Text = ['[',num2str(MEstack.line),']',ME.message];
                app.StatusLabel.BackgroundColor = [1.00,0.58,0.58];
                app.STARTROUGHALIGNINGButton.Enable = true;
                rethrow(ME)
            end
            app.STARTROUGHALIGNINGButton.Enable = true;
        end

        % Button pushed function: GetAligningDisplacementButton
        function GetAligningDisplacementButtonPushed(app, event)
            app.StatusLabel.Text = 'Getting Aligning Displacement...';
            app.StatusLabel.BackgroundColor = [0.87,0.94,0.30];
            drawnow;
            [optmizer, metric] = imregconfig('multimodal');
            optmizer.InitialRadius = optmizer.InitialRadius/2;
            optmizer.MaximumIterations = 500;
            try
                app.GetAligningDisplacementButton.Enable = false;
                drawnow;
                app.alignind = [];
                app.dx1 = []; app.dy1 = [];
                app.dx2 = []; app.dy2 = [];
                app.dx3 = []; app.dy3 = [];
                for ii = app.tifind'
                    try
                        load([char(app.selpath),'/',char(app.tifnames(app.tifind==ii))],'PSF');
                        simimg = PSF;
                        simimg = abs(simimg).^2;
                        simimg = simimg/max(simimg(:));
                        
%                         expimg = double(imread([char(app.selpath2),'/',char(app.tifnames2(app.tifind2==ii+app.SimExpInd(2)-app.SimExpInd(1)))]));
%                         expimg = expimg/max(expimg(:));
                        
                        app.StatusLabel.Text = ['Getting Aligning Displacement... [',num2str(ii),' - 1/3]'];
                        drawnow;
                        [dxi1,dyi1] = AliDispFunc(simimg(app.ROIboth(1,1):app.ROIboth(1,3),app.ROIboth(1,2):app.ROIboth(1,4)),...
                            app.preimg2(app.ROIboth(1,1):app.ROIboth(1,3),app.ROIboth(1,2):app.ROIboth(1,4)),optmizer, metric);
                        app.StatusLabel.Text = ['Getting Aligning Displacement... [',num2str(ii),' - 2/3]'];
                        drawnow;
                        [dxi2,dyi2] = AliDispFunc(simimg(app.ROIboth(2,1):app.ROIboth(2,3),app.ROIboth(2,2):app.ROIboth(2,4)),...
                            app.preimg2(app.ROIboth(2,1):app.ROIboth(2,3),app.ROIboth(2,2):app.ROIboth(2,4)),optmizer, metric);
                        app.StatusLabel.Text = ['Getting Aligning Displacement... [',num2str(ii),' - 3/3]'];
                        drawnow;
                        [dxi3,dyi3] = AliDispFunc(simimg(app.ROIboth(3,1):app.ROIboth(3,3),app.ROIboth(3,2):app.ROIboth(3,4)),...
                            app.preimg2(app.ROIboth(3,1):app.ROIboth(3,3),app.ROIboth(3,2):app.ROIboth(3,4)),optmizer, metric);
                        
                        app.dx1 = cat(1,app.dx1,dxi1);
                        app.dy1 = cat(1,app.dy1,dyi1);
                        app.dx2 = cat(1,app.dx2,dxi2);
                        app.dy2 = cat(1,app.dy2,dyi2);
                        app.dx3 = cat(1,app.dx3,dxi3);
                        app.dy3 = cat(1,app.dy3,dyi3);
                        %                         disp(ii)
                        app.alignind = cat(1,app.alignind,ii);
                        app.StatusLabel.Text = ['Getting Aligning Displacement... [',num2str(ii),']'];
                        drawnow;
                    catch ME
                        if (ii+app.SimExpInd(2)-app.SimExpInd(1))<app.tifind(1) || (ii+app.SimExpInd(2)-app.SimExpInd(1))>app.tifind(end)
                            app.StatusLabel.Text = ['Out of Range - Ignored... [',num2str(ii),']'];
                            drawnow;
                        else
                            rethrow(ME)
                        end
                    end
                end
                xyOffset = [app.dy1,app.dx1,app.dy2,app.dx2,app.dy3,app.dx3];
                for iOffset = 1:1:6
                    xyOffset(:,iOffset) = xyOffset(round(size(xyOffset,1)/2),iOffset);
                end
                app.dy1 = xyOffset(:,1);
                app.dx1 = xyOffset(:,2);
                app.dy2 = xyOffset(:,3);
                app.dx2 = xyOffset(:,4);
                app.dy3 = xyOffset(:,5);
                app.dx3 = xyOffset(:,6);
                app.StatusLabel.Text = 'Done!';
                app.StatusLabel.BackgroundColor = [0.47,0.82,0.82];
                app.GetAligningDisplacementButton.Enable = true;
                app.STARTROUGHALIGNINGButton.Enable = true;
            catch ME
                app.StatusLabel.Text = 'ERROR!';
                app.StatusLabel.BackgroundColor = [1.00,0.58,0.58];
                app.GetAligningDisplacementButton.Enable = true;
                rethrow(ME);
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                delete(app.dispApp)
            catch
            end
            try
                delete(app.showFeatureApp)
            catch
            end
            delete(app)
            
        end

        % Button pushed function: ShiftRawButton
        function ShiftRawButtonPushed(app, event)
            Centers = [(app.ROI(1,1) + app.ROI(1,3))/2-73,(app.ROI(1,2) + app.ROI(1,4))/2-2;
                       (app.ROI(2,1) + app.ROI(2,3))/2-73,(app.ROI(2,2) + app.ROI(2,4))/2-2; 
                       (app.ROI(3,1) + app.ROI(3,3))/2-73,(app.ROI(3,2) + app.ROI(3,4))/2-2];
            disp(Centers)
            app.ROIboth = round([Centers(:,1)-250,Centers(:,2)-250,Centers(:,1)+250,Centers(:,2)+250]);
            app.preimg2 = shiftRaw(app.preimg2,Centers,Centers(2,2)-Centers(1,2),app.dcentpos_re,app.ScaleFactorEditField.Value);
            app.preimg2 = app.preimg2/max(app.preimg2(:));
            imagesc(app.preimg2,'Parent',app.UIAxes_2);
        end

        % Button pushed function: AlignManuallyButton
        function AlignManuallyButtonPushed(app, event)
            app.manualAli = ManualAli(app,app.preimg,app.preimg2,app.ROIboth);         
        end

        % Button pushed function: GetCentroidsButton
        function GetCentroidsButtonPushed(app, event)
            app.StatusLabel.Text = 'Getting Centroids...';
            drawnow;
            positions = [];
            for ii = app.tifind'
                try
                    load([char(app.selpath),'/',char(app.tifnames(app.tifind==ii))],'PSF');
                    simimg = PSF;
                    simimg = abs(simimg).^2;
                    simimg = simimg/max(simimg(:));
                    sec1 = simimg(app.ROIboth(1,1):app.ROIboth(1,3),app.ROIboth(1,2):app.ROIboth(1,4));
                    sec2 = simimg(app.ROIboth(2,1):app.ROIboth(2,3),app.ROIboth(2,2):app.ROIboth(2,4));
                    sec3 = simimg(app.ROIboth(3,1):app.ROIboth(3,3),app.ROIboth(3,2):app.ROIboth(3,4));
                    [posx1,posy1] = app.imcentroid(sec1);
                    [posx2,posy2] = app.imcentroid(sec2);
                    [posx3,posy3] = app.imcentroid(sec3);
                    positions = cat(1,positions,...
                        [posx1+app.ROIboth(1,2),posy1+app.ROIboth(1,1),...
                         posx2+app.ROIboth(2,2),posy2+app.ROIboth(2,1),...
                         posx3+app.ROIboth(3,2),posy3+app.ROIboth(3,1)]);
                    disp([ii,round([posx1+app.ROIboth(1,2),posy1+app.ROIboth(1,1),...
                        posx2+app.ROIboth(2,2),posy2+app.ROIboth(2,1),...
                        posx3+app.ROIboth(3,2),posy3+app.ROIboth(3,1)])])
                    app.StatusLabel.Text = ['Getting Centroids ',num2str(ii),' done!'];
                    drawnow;
                catch ME
                    if (ii+app.SimExpInd(2)-app.SimExpInd(1))<app.tifind(1) || (ii+app.SimExpInd(2)-app.SimExpInd(1))>app.tifind(end)
                        app.StatusLabel.Text = ['Out of Range - Ignored... [',num2str(ii),']'];
                        drawnow;
                    else
                        rethrow(ME)
                    end
                end
            end
            disp(positions(app.tifind==50100,:))
            app.centpos_re = (positions - positions(app.tifind==50100,:));
        end

        % Button pushed function: FineTuneButton
        function FineTuneButtonPushed(app, event)
            polyscale = 2;
            p1 = polyfit(app.tifind,app.centpos_re(:,1),polyscale);
            p2 = polyfit(app.tifind,app.centpos_re(:,2),polyscale);
            p3 = polyfit(app.tifind,app.centpos_re(:,3),polyscale);
            p4 = polyfit(app.tifind,app.centpos_re(:,4),polyscale);
            p5 = polyfit(app.tifind,app.centpos_re(:,5),polyscale);
            p6 = polyfit(app.tifind,app.centpos_re(:,6),polyscale);
            app.dcentpos_re = [polyval(p2,app.tifind)-polyval(p2,app.tifind(round(length(app.tifind)/2))),...
                               polyval(p1,app.tifind)-polyval(p1,app.tifind(round(length(app.tifind)/2))),...
                               polyval(p4,app.tifind)-polyval(p4,app.tifind(round(length(app.tifind)/2))),...
                               polyval(p3,app.tifind)-polyval(p3,app.tifind(round(length(app.tifind)/2))),...
                               polyval(p6,app.tifind)-polyval(p6,app.tifind(round(length(app.tifind)/2))),...
                               polyval(p5,app.tifind)-polyval(p5,app.tifind(round(length(app.tifind)/2)))];
            app.dcentpos = [polyval(p1,app.tifind)-app.centpos_re(:,1),...
                            polyval(p2,app.tifind)-app.centpos_re(:,2),...
                            polyval(p3,app.tifind)-app.centpos_re(:,3),...
                            polyval(p4,app.tifind)-app.centpos_re(:,4),...
                            polyval(p5,app.tifind)-app.centpos_re(:,5),...
                            polyval(p6,app.tifind)-app.centpos_re(:,6)];
            app.dcentpos = round(app.dcentpos * app.ScaleFactorEditField.Value);
            figure(6),
            subplot(121),plot(app.dcentpos_re);
            subplot(122),plot(app.dcentpos)
%             figure(66),plot((app.dcentpos));
        end

        % Button pushed function: STARTFINEALIGNINGButton
        function STARTFINEALIGNINGButtonPushed(app, event)
            app.StatusLabel.Text = 'Aligning...';
            app.StatusLabel.BackgroundColor = [0.87,0.94,0.30];
            drawnow;
            try
                app.STARTROUGHALIGNINGButton.Enable = false;
                for ii = app.tifind'
                    
                    load([char(app.selpath),'/',char(app.tifnames(app.tifind==ii))],'PSF');
                    try
                        disp([app.alignind(app.alignind==ii),app.dy1(app.alignind==ii),app.dx1(app.alignind==ii)])
   
                        PSF1 =  PSF(app.ROIboth(1,1):app.ROIboth(1,3),app.ROIboth(1,2):app.ROIboth(1,4));
                        PSF1 = imresize(PSF1,app.ScaleFactorEditField.Value);
                        PSF1 = circshift(PSF1,[app.dcentpos(app.tifind==ii,2),app.dcentpos(app.tifind==ii,1)]);
                        PSF1 = imresize(PSF1,1.0 / app.ScaleFactorEditField.Value);
                        PSF(app.ROIboth(1,1):app.ROIboth(1,3),app.ROIboth(1,2):app.ROIboth(1,4)) = PSF1;
                        
                        PSF2 =  PSF(app.ROIboth(2,1):app.ROIboth(2,3),app.ROIboth(2,2):app.ROIboth(2,4));
                        PSF2 = imresize(PSF2,app.ScaleFactorEditField.Value);
                        PSF2 = circshift(PSF2,[app.dcentpos(app.tifind==ii,4),app.dcentpos(app.tifind==ii,3)]);
                        PSF2 = imresize(PSF2,1.0 / app.ScaleFactorEditField.Value);
                        PSF(app.ROIboth(2,1):app.ROIboth(2,3),app.ROIboth(2,2):app.ROIboth(2,4)) = PSF2;
                        
                        PSF3 =  PSF(app.ROIboth(3,1):app.ROIboth(3,3),app.ROIboth(3,2):app.ROIboth(3,4));
                        PSF3 = imresize(PSF3,app.ScaleFactorEditField.Value);
                        PSF3 = circshift(PSF3,[app.dcentpos(app.tifind==ii,6),app.dcentpos(app.tifind==ii,5)]);
                        PSF3 = imresize(PSF3,1.0 / app.ScaleFactorEditField.Value);
                        PSF(app.ROIboth(3,1):app.ROIboth(3,3),app.ROIboth(3,2):app.ROIboth(3,4)) = PSF3;
                    catch
                        app.StatusLabel.Text = ['Aligning Excluded! [',num2str(ii),']'];
                        app.StatusLabel.BackgroundColor = [0.47,0.82,0.82];
                    end
                    %                     PSF = simimg;
                    save([char([app.savepath,'/',app.savefine]),'/',char(app.tifnames(app.tifind==ii))],'PSF')
                    app.StatusLabel.Text = ['Aligning... [',num2str(ii),']'];
                    drawnow;
                end
                app.StatusLabel.Text = 'Done!';
                app.StatusLabel.BackgroundColor = [0.47,0.82,0.82];
                drawnow;
            catch ME
                MEstack = ME.stack(3);
                %                 disp(MEstack.line)
                app.StatusLabel.Text = ['[',num2str(MEstack.line),']',ME.message];
                app.StatusLabel.BackgroundColor = [1.00,0.58,0.58];
                app.STARTROUGHALIGNINGButton.Enable = true;
                rethrow(ME)
            end
            app.STARTROUGHALIGNINGButton.Enable = true;
        end

        % Button pushed function: MakefoldersButton
        function MakefoldersButtonPushed(app, event)
            psfdate = datestr(now,'yyyymmdd');
            app.saverough = ['PSFFLF',psfdate,'corr',app.FluorColorDropDown.Value,app.MediumDropDown.Value];
            app.savefine = ['PSFFLF',psfdate,'finecorr',app.FluorColorDropDown.Value,app.MediumDropDown.Value];
            if ~exist([app.savepath,'/',app.saverough],'dir')
                mkdir([app.savepath,'/',app.saverough])
            end
            if ~exist([app.savepath,'/',app.savefine],'dir')
                mkdir([app.savepath,'/',app.savefine])
            end
        end

        % Button pushed function: STACKPSFButton
        function STACKPSFButtonPushed(app, event)
            
            stackind = (50000-1000*round(app.AxialRangeumEditField.Value/2)):100:(50000+1000*round(app.AxialRangeumEditField.Value/2));
            FLFPSF = zeros(size(app.preimg,1),size(app.preimg,2),length(stackind));
            for ii = stackind
                load([app.savepath,'/',app.savefine,'/',char(app.tifnames(app.tifind==ii))],'PSF')
                PSF = abs(PSF(1:size(app.preimg,1),1:size(app.preimg,2))).^2;
                FLFPSF(:,:,round(ii/100-stackind(1)/100+1)) = PSF;
                app.StatusLabel.Text = ['Stacking #',num2str(ii),'-layer PSF...'];
                drawnow;
            end
            save([app.savepath,'/','PSFFLFint_',datestr(now,'yyyymmdd'),'_',...
                app.FluorColorDropDown.Value,'_',app.MediumDropDown.Value,'_',...
                num2str(round((stackind(end)-stackind(1))/1000)),'um.mat'],'FLFPSF')
            app.StatusLabel.Text = 'PSF stacking done!';
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, 'Experimental PSF')
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Simulated PSF')
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.SimulatedPSFLabel = uilabel(app.GridLayout);
            app.SimulatedPSFLabel.Text = 'Simulated PSF';
            app.ExperimentalPSFLabel = uilabel(app.GridLayout);
            app.ExperimentalPSFLabel.Text = 'Experimental PSF';
            app.GetSimROIsButton = uibutton(app.GridLayout, 'push');
            app.GetSimROIsButton.ButtonPushedFcn = createCallbackFcn(app, @GetSimROIsButtonPushed, true);
            app.GetSimROIsButton.Text = 'Get Sim ROIs';
            app.GetExpROIsButton = uibutton(app.GridLayout, 'push');
            app.GetExpROIsButton.ButtonPushedFcn = createCallbackFcn(app, @GetExpROIsButtonPushed, true);
            app.GetExpROIsButton.Text = 'Get Exp ROIs';
            app.BackwardButton = uibutton(app.GridLayout, 'push');
            app.BackwardButton.ButtonPushedFcn = createCallbackFcn(app, @BackwardButtonPushed, true);
            app.BackwardButton.Text = '◀ Backward';
            app.ForwardButton = uibutton(app.GridLayout, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward ▶';
            app.OpenSimulatedPSFFolderButton = uibutton(app.GridLayout, 'push');
            app.OpenSimulatedPSFFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSimulatedPSFFolderButtonPushed, true);
            app.OpenSimulatedPSFFolderButton.Text = 'Open Simulated PSF Folder';
            app.OpenExperimentalPSFFolderButton = uibutton(app.GridLayout, 'push');
            app.OpenExperimentalPSFFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenExperimentalPSFFolderButtonPushed, true);
            app.OpenExperimentalPSFFolderButton.Text = 'Open Experimental PSF Folder';
            app.STARTROUGHALIGNINGButton = uibutton(app.GridLayout, 'push');
            app.STARTROUGHALIGNINGButton.ButtonPushedFcn = createCallbackFcn(app, @STARTROUGHALIGNINGButtonPushed, true);
            app.STARTROUGHALIGNINGButton.Text = 'START ROUGH ALIGNING';
            app.SimExpFrameNoLabel = uilabel(app.GridLayout);
            app.SimExpFrameNoLabel.Text = 'Sim-Exp Frame No';
            app.SaveFolderButton = uibutton(app.GridLayout, 'push');
            app.SaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFolderButtonPushed, true);
            app.SaveFolderButton.Text = 'Save Folder';
            app.SavePSFLabel = uilabel(app.GridLayout);
            app.SavePSFLabel.Text = 'Save PSF';
            app.ExportVarsButton = uibutton(app.GridLayout, 'push');
            app.ExportVarsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportVarsButtonPushed, true);
            app.ExportVarsButton.Text = 'Export Vars';
            app.ShowROIsButton = uibutton(app.GridLayout, 'push');
            app.ShowROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowROIsButtonPushed, true);
            app.ShowROIsButton.Text = 'Show ROIs';
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status';
            app.GetAligningDisplacementButton = uibutton(app.GridLayout, 'push');
            app.GetAligningDisplacementButton.ButtonPushedFcn = createCallbackFcn(app, @GetAligningDisplacementButtonPushed, true);
            app.GetAligningDisplacementButton.Text = 'Get Aligning Displacement';
            app.GetCentroidsButton = uibutton(app.GridLayout, 'push');
            app.GetCentroidsButton.ButtonPushedFcn = createCallbackFcn(app, @GetCentroidsButtonPushed, true);
            app.GetCentroidsButton.Text = 'Get Centroids';
            app.FineTuneButton = uibutton(app.GridLayout, 'push');
            app.FineTuneButton.ButtonPushedFcn = createCallbackFcn(app, @FineTuneButtonPushed, true);
            app.FineTuneButton.Text = 'Fine Tune';
            app.STARTFINEALIGNINGButton = uibutton(app.GridLayout, 'push');
            app.STARTFINEALIGNINGButton.ButtonPushedFcn = createCallbackFcn(app, @STARTFINEALIGNINGButtonPushed, true);
            app.STARTFINEALIGNINGButton.Text = 'START FINE ALIGNING';
            app.ShiftRawButton = uibutton(app.GridLayout, 'push');
            app.ShiftRawButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftRawButtonPushed, true);
            app.ShiftRawButton.Text = 'Shift Raw';
            app.AlignManuallyButton = uibutton(app.GridLayout, 'push');
            app.AlignManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @AlignManuallyButtonPushed, true);
            app.AlignManuallyButton.Text = 'Align Manually';
            app.FluorColorDropDownLabel = uilabel(app.GridLayout);
            app.FluorColorDropDownLabel.Text = 'Fluor. Color';
            app.FluorColorDropDown = uidropdown(app.GridLayout);
            app.FluorColorDropDown.Items = {'Red', 'Green', 'Blue', 'Purple'};
            app.FluorColorDropDown.Value = 'Red';
            app.MediumDropDownLabel = uilabel(app.GridLayout);
            app.MediumDropDownLabel.Text = 'Medium';
            app.MediumDropDown = uidropdown(app.GridLayout);
            app.MediumDropDown.Items = {'Gly', 'H2O'};
            app.MediumDropDown.Value = 'Gly';
            app.MakefoldersButton = uibutton(app.GridLayout, 'push');
            app.MakefoldersButton.ButtonPushedFcn = createCallbackFcn(app, @MakefoldersButtonPushed, true);
            app.MakefoldersButton.Text = 'Make folders';
            app.FineTuneResizingScaleLabel = uilabel(app.GridLayout);
            app.FineTuneResizingScaleLabel.Text = 'Scale Factor: ';
            app.ScaleFactorEditField = uieditfield(app.GridLayout, 'numeric');
            app.ScaleFactorEditField.Value = 10;
            app.AxialRangeumEditFieldLabel = uilabel(app.GridLayout);
            app.AxialRangeumEditFieldLabel.Text = 'Axial Range (um): ';
            app.AxialRangeumEditField = uieditfield(app.GridLayout, 'numeric');
            app.AxialRangeumEditField.Value = 10;
            app.STACKPSFButton = uibutton(app.GridLayout, 'push');
            app.STACKPSFButton.ButtonPushedFcn = createCallbackFcn(app, @STACKPSFButtonPushed, true);
            app.STACKPSFButton.Text = 'STACK PSF';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PSFCorrManual

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end