classdef DataProc4HRFLFM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HRFLFMDP                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        AboutHRFLFMDPMenu              matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        Image2                         matlab.ui.control.Image
        GrayButton                     matlab.ui.control.Button
        RainbowButton                  matlab.ui.control.Button
        JetButton                      matlab.ui.control.Button
        InfernoButton                  matlab.ui.control.Button
        CyanButton                     matlab.ui.control.Button
        GreenButton                    matlab.ui.control.Button
        MagentaButton                  matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        BKGSubTab                      matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        RollingentirefolderCheckBox    matlab.ui.control.CheckBox
        SubtractentirefolderCheckBox   matlab.ui.control.CheckBox
        PreviewoffsetsubtractionCheckBox  matlab.ui.control.CheckBox
        SaveRolledImageButton          matlab.ui.control.Button
        PreviewresultsCheckBox         matlab.ui.control.CheckBox
        RadiusEditField                matlab.ui.control.NumericEditField
        RadiusLabel                    matlab.ui.control.Label
        UsepresmoothingCheckBox        matlab.ui.control.CheckBox
        UseparaboloidrollingCheckBox   matlab.ui.control.CheckBox
        LightbackgroundCheckBox        matlab.ui.control.CheckBox
        BackgroundsubtractionbyrollingballalgorithmLabel  matlab.ui.control.Label
        SUBTRACTButton                 matlab.ui.control.Button
        OffsetpathLabel                matlab.ui.control.Label
        OptionsButtonGroup             matlab.ui.container.ButtonGroup
        LOADButton                     matlab.ui.control.Button
        BKGOffsetEditField             matlab.ui.control.NumericEditField
        LoadMatButton                  matlab.ui.control.RadioButton
        BKGOffsetButton                matlab.ui.control.RadioButton
        BackgroundsubtractionbyoffsetLabel  matlab.ui.control.Label
        ACsNTab                        matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        ButtonGroup                    matlab.ui.container.ButtonGroup
        ProcessEntireDirectoryButton   matlab.ui.control.RadioButton
        ProcessSelectedOneButton       matlab.ui.control.RadioButton
        ShowOriginalImageButton        matlab.ui.control.Button
        ACsNDenoiseButton              matlab.ui.control.Button
        nmLabel_2                      matlab.ui.control.Label
        PixelSizeEditField             matlab.ui.control.NumericEditField
        PixelSizeEditFieldLabel        matlab.ui.control.Label
        LoadfileforbothoffsetandgainButton  matlab.ui.control.Button
        ACsNLabel                      matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        GainpathLabel                  matlab.ui.control.Label
        GainsettingsButtonGroup        matlab.ui.container.ButtonGroup
        LOADButton_3                   matlab.ui.control.Button
        GainEditField                  matlab.ui.control.NumericEditField
        LoadMatButton_3                matlab.ui.control.RadioButton
        ManualButton_2                 matlab.ui.control.RadioButton
        OffsetsettingsButtonGroup      matlab.ui.container.ButtonGroup
        LOADButton_2                   matlab.ui.control.Button
        OffsetEditField                matlab.ui.control.NumericEditField
        LoadMatButton_2                matlab.ui.control.RadioButton
        ManualButton                   matlab.ui.control.RadioButton
        SaveACsNedImageButton          matlab.ui.control.Button
        OffsetpathLabel_2              matlab.ui.control.Label
        nmLabel                        matlab.ui.control.Label
        WavelengthEditField            matlab.ui.control.NumericEditField
        WavelengthEditFieldLabel       matlab.ui.control.Label
        NumAperEditField               matlab.ui.control.NumericEditField
        NumAperEditFieldLabel          matlab.ui.control.Label
        SizingTab                      matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        MatchingButton                 matlab.ui.control.Button
        SizematchingtoasquareLabel     matlab.ui.control.Label
        pixelsLabel_2                  matlab.ui.control.Label
        PitchSizeEditField             matlab.ui.control.NumericEditField
        PitchSizeLabel                 matlab.ui.control.Label
        ColBtmEditField                matlab.ui.control.NumericEditField
        ColBtmEditFieldLabel           matlab.ui.control.Label
        RowBtmEditField                matlab.ui.control.NumericEditField
        RowBtmEditFieldLabel           matlab.ui.control.Label
        ColTREditField                 matlab.ui.control.NumericEditField
        ColTREditFieldLabel            matlab.ui.control.Label
        RowTREditField                 matlab.ui.control.NumericEditField
        RowTREditFieldLabel            matlab.ui.control.Label
        ColTLEditField                 matlab.ui.control.NumericEditField
        ColTLEditFieldLabel            matlab.ui.control.Label
        RowTLEditField                 matlab.ui.control.NumericEditField
        RowTLEditFieldLabel            matlab.ui.control.Label
        CutandSavetheCutImagesButton   matlab.ui.control.Button
        CuttingPreviewButton           matlab.ui.control.Button
        pixelsLabel                    matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        pxsofLabel                     matlab.ui.control.Label
        SetFOVsizeEditField            matlab.ui.control.NumericEditField
        SetFOVsizeEditFieldLabel       matlab.ui.control.Label
        PullOutCircularMasksCheckBox   matlab.ui.control.CheckBox
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        RECONTab                       matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        ReplaceexistingfilesCheckBox   matlab.ui.control.CheckBox
        ModeDropDown                   matlab.ui.control.DropDown
        ModeDropDownLabel              matlab.ui.control.Label
        FitPSFElementalDisplacementsButton  matlab.ui.control.Button
        FitPSFElementalDisplacementsLabel  matlab.ui.control.Label
        ReconstructedImageSavingOptionsLabel  matlab.ui.control.Label
        CutShiftEditField              matlab.ui.control.NumericEditField
        CutShiftEditFieldLabel         matlab.ui.control.Label
        CropWidthEditField             matlab.ui.control.NumericEditField
        CropWidthEditFieldLabel        matlab.ui.control.Label
        CropHeightEditField            matlab.ui.control.NumericEditField
        CropHeightEditFieldLabel       matlab.ui.control.Label
        ReconstructionFilePathLabel    matlab.ui.control.Label
        ReconstructionFileButton       matlab.ui.control.Button
        RLDeconvolutionButton          matlab.ui.control.Button
        IterNumEditField               matlab.ui.control.NumericEditField
        IterNumEditFieldLabel          matlab.ui.control.Label
        FLFMFilePathLabel              matlab.ui.control.Label
        PSFFilePathLabel               matlab.ui.control.Label
        FLFMFileButton                 matlab.ui.control.Button
        PSFFileButton                  matlab.ui.control.Button
        DISPTab                        matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        EndIndexSpinner                matlab.ui.control.Spinner
        EndIndexSpinnerLabel           matlab.ui.control.Label
        StartIndexSpinner              matlab.ui.control.Spinner
        StartIndexSpinnerLabel         matlab.ui.control.Label
        ProcessEntireFolderButton      matlab.ui.control.Button
        SaveSelectedImageButton        matlab.ui.control.Button
        DisplayStack3DButton           matlab.ui.control.Button
        IntensityGainEditField         matlab.ui.control.NumericEditField
        IntensityGainEditFieldLabel    matlab.ui.control.Label
        ScalebarPanel                  matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        PositionDropDown               matlab.ui.control.DropDown
        PositionDropDownLabel          matlab.ui.control.Label
        barPixelsizeEditField          matlab.ui.control.NumericEditField
        PixelsizeEditFieldLabel        matlab.ui.control.Label
        LengthEditField                matlab.ui.control.NumericEditField
        LengthEditFieldLabel           matlab.ui.control.Label
        IntDrightwidthEditField        matlab.ui.control.NumericEditField
        IntDrightwidthEditFieldLabel   matlab.ui.control.Label
        IntDleftwidthEditField         matlab.ui.control.NumericEditField
        IntDleftwidthEditFieldLabel    matlab.ui.control.Label
        IntensityOffsetEditField       matlab.ui.control.NumericEditField
        IntensityOffsetEditFieldLabel  matlab.ui.control.Label
        CroppingPanel                  matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        colend                         matlab.ui.control.NumericEditField
        colstart                       matlab.ui.control.NumericEditField
        rowend                         matlab.ui.control.NumericEditField
        rowstart                       matlab.ui.control.NumericEditField
        OpenDirectoryButton            matlab.ui.control.Button
        ReadyLabel                     matlab.ui.control.Label
        Tree                           matlab.ui.container.Tree
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        initialized = 0;
        selpath = '.'; % Open directory
        savepath = '.'; % Save directory
        %         averageOpt = 'cross';
        preimg = 0.75 * ones(1000,1000);
        imgdatatype = "double";
        maxrow1 = 1;
        maxcol1 = 1;
        maxrow2 = 1;
        maxcol2 = 1;
        maxrow3 = 1;
        maxcol3 = 1;
        ctrrow = 1;
        ctrcol = 1;
        himg = 1;
        cutr = 1;
        boundup = 1;
        bounddw = 1;
        boundlf = 1;
        boundrt = 1;
        dcentpos = [];
        
        OffsetMat = [];
        GainMat = [];

        clmap = gray(256);
    end
    
    methods (Access = public)
        function readylabel_signal(app,msg,waiting)
            app.ReadyLabel.Text = msg;
            if waiting
                app.ReadyLabel.BackgroundColor = [0.85,0.41,0.41];
            else
                app.ReadyLabel.BackgroundColor = [0.52,0.85,0.41];
            end
            drawnow;
        end
        
        function imshow_norm(app,img)
            img = double(img);
            switch app.imgdatatype
                case "uint16"
                    img = uint16(img/max(img(:))*65535);
                case "uint8"
                    img = uint8(img/max(img(:))*255);
                case "double"
                    img = img/max(img(:));
            end
            app.preimg = img;
            app.himg = imshow(app.preimg,'parent', app.UIAxes,'Colormap',app.clmap);
            drawnow;
        end
        
        function imgout = imtypeconvert(app,img)
            img = double(img);
            switch app.imgdatatype
                case "uint16"
                    imgout = uint16(img);
                case "uint8"
                    imgout = uint8(img);
                case "double"
                    imgout = img;            
            end
        end
        
        function imgout = offsetsubtraction(app)
            selectedNodes_text = app.Tree.SelectedNodes.Text;
            if string(selectedNodes_text(end-3:end))==".tif"
                app.readylabel_signal('Subtracting offset...',true);
                tifname2open = [app.selpath,'/',selectedNodes_text];
                app.preimg = double(imread(tifname2open));
                if string(app.OptionsButtonGroup.SelectedObject.Text)=="BKG Offset"
                    app.preimg = (app.preimg - app.OffsetEditField.Value).*double((app.preimg - app.OffsetEditField.Value)>=0);
                    imgout = app.imtypeconvert(app.preimg);
                    app.imshow_norm(app.preimg);
                    app.readylabel_signal('Offset subtraction done!',false);
                else
                    try
                        app.preimg = (app.preimg - app.OffsetMat).*double((app.preimg - app.OffsetMat)>=0);
                        imgout = app.imtypeconvert(app.preimg);
                        app.imshow_norm(app.preimg);
                        app.readylabel_signal('Offset subtraction done!',false);
                    catch ME
                        app.readylabel_signal(ME.message,true);
                    end
                end
            else
                app.readylabel_signal('Image previewing: no image selected!',false);
            end
        end

        function show_original_img(app)
            selectedNodes_text = app.Tree.SelectedNodes.Text;
            if string(selectedNodes_text(end-3:end))==".tif"
                app.readylabel_signal('...',true);
                app.preimg = imread( [app.selpath,'/',selectedNodes_text]);
                app.imshow_norm(app.preimg);
                app.readylabel_signal('Showing original image!',false);
            else
                app.readylabel_signal('Image previewing: no image selected!',false);
            end
        end
    
        function show_colormap(app)
            clmapmat = permute(repmat(flipud(app.clmap),1,1,10),[1,3,2]);
            app.Image2.ImageSource = uint8(clmapmat*255);
            % imshow(uint8(repmat((255:-1:0),10,1)'),'Parent',app.UIAxes_2,'Colormap',app.clmap)
        end

    end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.IterNumEditField.Value = 10;
            app.himg = imshow(app.preimg,'parent', app.UIAxes);
            axis(app.UIAxes,'tight');
            % axis(app.UIAxes_2,'image');
            app.show_colormap()
            warning off
            
            if string(app.OptionsButtonGroup.SelectedObject.Text) == "BKG Offset"
                app.BKGOffsetEditField.Enable = true;
                app.LOADButton.Enable = false;
                app.OffsetpathLabel.Enable = false;
            else
                app.BKGOffsetEditField.Enable = false;
                app.LOADButton.Enable = true;
                app.OffsetpathLabel.Enable = true;
            end
            
            if string(app.OffsetsettingsButtonGroup.SelectedObject.Text) == "Manual"
                app.OffsetEditField.Enable = true;
                app.LOADButton_2.Enable = false;
                app.OffsetpathLabel_2.Enable = false;
            else
                app.OffsetEditField.Enable = false;
                app.LOADButton_2.Enable = true;
                app.OffsetpathLabel_2.Enable = true;
            end
            
            if string(app.GainsettingsButtonGroup.SelectedObject.Text) == "Manual"
                app.GainEditField.Enable = true;
                app.LOADButton_3.Enable = false;
                app.GainpathLabel.Enable = false;
            else
                app.GainEditField.Enable = false;
                app.LOADButton_3.Enable = true;
                app.GainpathLabel.Enable = true;
            end
            
            app.RowTREditField.Value = app.RowTLEditField.Value;
            app.RowBtmEditField.Value = app.RowTLEditField.Value + round(app.PitchSizeEditField.Value/2*sqrt(3));
            app.ColTREditField.Value = app.ColTLEditField.Value + app.PitchSizeEditField.Value;
            app.ColBtmEditField.Value = app.ColTLEditField.Value + round(app.PitchSizeEditField.Value/2);

%             disp(['Current folder: ',fileparts(mfilename('fullpath'))])
            addpath ([fileparts(mfilename('fullpath')),'\FijiImageJ\scripts\'])
            addpath ([fileparts(mfilename('fullpath')),'\Functions\'])
            addpath ([fileparts(mfilename('fullpath')),'\OtherFuncs\'])
        end

        % Button pushed function: OpenDirectoryButton
        function OpenDirectoryButtonPushed(app, event)
            if app.initialized ==0
                try
                    app.selpath = 'Z:\Xuanwen\FLFMuf\ExpData';
                catch
                    app.selpath = '.\';
                end
            end
            selpath0 = uigetdir(app.selpath);
            if length(char(selpath0))~=1
                app.selpath = selpath0;
                delete(app.Tree.Children)
                app.savepath = selpath0;
                try
                    % disp(['Open Directory: ',app.selpath])
                    S = split(app.selpath,'\');
                    uitreepath = uitreenode(app.Tree,'Text',char(S{end}));
                    subfolder = dir(app.selpath);
                    for i = 3:length(subfolder)
                        uitreenode(uitreepath,'Text',subfolder(i).name);
                    end
                    expand(app.Tree,'all')
                catch
                    
                end
            end
            app.initialized = 1;           
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close(app.HRFLFMDP)
        end

        % Button pushed function: MatchingButton
        function MatchingButtonPushed(app, event)
            mkdir([app.selpath,'_sq\'])
            filepath = dir([app.selpath,'\*.tif']);
            filenames = string({filepath(:).name}');
            for i = 1:length(filenames)
                try
                    img = imread([app.selpath,'\',char(filenames(i))]);
                    baseline = mean([img(1:5,1:5),img(1:5,end-4:end);...
                                     img(end-4:end,1:5),img(end-4:end,end-4:end)],"all");
                    % imgout = [repmat(img(1,:)*0,round((app.SetFOVsizeEditField.Value-size(img,1))/2),1);...
                    %     img;repmat(img(end,:)*0,app.SetFOVsizeEditField.Value-size(img,1)-round((app.SetFOVsizeEditField.Value-size(img,1))/2),1)];
                    % imgout = [repmat(imgout(:,1)*0,1,round((app.SetFOVsizeEditField.Value-size(imgout,2))/2)),...
                    %     imgout,repmat(imgout(:,end)*0,1,app.SetFOVsizeEditField.Value-size(imgout,2)-round((app.SetFOVsizeEditField.Value-size(imgout,2))/2))];
                    imgout = [SynCamNoises([ceil((app.SetFOVsizeEditField.Value-size(img,1))/2),size(img,2)],1.0,baseline);...
                        img;SynCamNoises([floor((app.SetFOVsizeEditField.Value-size(img,1))/2),size(img,2)],1.0,baseline)];
                    imgout2 = [SynCamNoises([size(imgout,1),ceil((app.SetFOVsizeEditField.Value-size(imgout,2))/2)],1.0,baseline),...
                        imgout,SynCamNoises([size(imgout,1),floor((app.SetFOVsizeEditField.Value-size(imgout,2))/2)],1.0,baseline)];
                    imwrite(imgout2,[app.selpath,'_sq\',char(filenames(i))])
                    app.readylabel_signal(['Saved! (.\..._sq\',char(filenames(i))],false)
                catch ME
                    app.readylabel_signal(['Image Unsaved :-( ',num2str(i)],true);
                    rethrow(ME);
                end
            end
            app.readylabel_signal('Size matching done!',false)
        end

        % Button pushed function: CuttingPreviewButton
        function CuttingPreviewButtonPushed(app, event)
            app.maxrow1 = app.RowTLEditField.Value;
            app.maxcol1 = app.ColTLEditField.Value;
            app.maxrow2 = app.RowTREditField.Value;
            app.maxcol2 = app.ColTREditField.Value;
            app.maxrow3 = app.RowBtmEditField.Value;
            app.maxcol3 = app.ColBtmEditField.Value;
            app.ctrrow = round((app.maxrow1 + app.maxrow2 + app.maxrow3)/3  + (app.maxcol2 - app.maxcol1)/4/sqrt(3));
            app.ctrcol = round((app.maxcol1 + app.maxcol2 + app.maxcol3)/3);
            app.boundup = round(max(app.ctrrow - app.SetFOVsizeEditField.Value/2+1,1));
            app.bounddw = round(min(app.ctrrow + app.SetFOVsizeEditField.Value/2,str2double(app.Label.Text)));
            app.boundlf = round(max(app.ctrcol - app.SetFOVsizeEditField.Value/2+1,1));
            app.boundrt = round(min(app.ctrcol + app.SetFOVsizeEditField.Value/2,str2double(app.Label.Text)));
            app.readylabel_signal("Cropping location: " + num2str([app.boundup,app.bounddw,app.boundlf,app.boundrt]),false)
%             disp([app.boundup,app.bounddw,app.boundlf,app.boundrt])
            preimgROI = double(app.preimg);
            preimgROI = preimgROI/max(preimgROI(:));
            preimgROI(app.boundup:app.boundup+6,app.boundlf:app.boundrt) = 1.0;
            preimgROI(app.bounddw-6:app.bounddw,app.boundlf:app.boundrt) = 1.0;
            preimgROI(app.boundup:app.bounddw,app.boundlf:app.boundlf+6) = 1.0;
            preimgROI(app.boundup:app.bounddw,app.boundrt-6:app.boundrt) = 1.0;
            app.himg = imshow(preimgROI,'parent', app.UIAxes);
            
            if app.PullOutCircularMasksCheckBox.Value
                app.cutr = round(sqrt(min([(app.maxrow1-app.maxrow2)^2 + (app.maxcol1-app.maxcol2)^2,...
                                           (app.maxrow1-app.maxrow3)^2 + (app.maxcol1-app.maxcol3)^2,...
                                           (app.maxrow3-app.maxrow2)^2 + (app.maxcol3-app.maxcol2)^2]))/2);           
                [X,Y] = meshgrid(1:1:size(preimgROI,2),1:1:size(preimgROI,1));
                circle1 = abs(sqrt((X-app.maxcol1).^2 + (Y-app.maxrow1).^2)-app.cutr)<4;
                circle2 = abs(sqrt((X-app.maxcol2).^2 + (Y-app.maxrow2).^2)-app.cutr)<4;
                circle3 = abs(sqrt((X-app.maxcol3).^2 + (Y-app.maxrow3).^2)-app.cutr)<4;
                circles = logical(circle1) | logical(circle2) | logical(circle3);
                preimgROI(circles==1) = 1.0;
                app.himg = imshow(preimgROI,'parent', app.UIAxes);
            end
        end

        % Button pushed function: CutandSavetheCutImagesButton
        function CutandSavetheCutImagesButtonPushed(app, event)
            boundmat = [app.boundup,app.bounddw,app.boundlf,app.boundrt];    
            if app.PullOutCircularMasksCheckBox.Value == true
                [X,Y] = meshgrid(1:1:size(app.preimg,2),1:1:size(app.preimg,1));
                circle1 = sqrt((X-app.maxcol1).^2 + (Y-app.maxrow1).^2)<=app.cutr;
                circle2 = sqrt((X-app.maxcol2).^2 + (Y-app.maxrow2).^2)<=app.cutr;
                circle3 = sqrt((X-app.maxcol3).^2 + (Y-app.maxrow3).^2)<=app.cutr;
                circlemat = logical(circle1) | logical(circle2) | logical(circle3);
                app.readylabel_signal('Running circle cutting... ',true);
                CutCircleSave(app.selpath,boundmat,circlemat);
                app.readylabel_signal('Circle cutting completed!',false);
            else
                app.readylabel_signal('Running circle cutting... ',true);
                CutSave(app.selpath,boundmat);
                app.readylabel_signal('Circle cutting completed!',false);
            end
            
        end

        % Button pushed function: PSFFileButton
        function PSFFileButtonPushed(app, event)
            app.readylabel_signal('Acquiring PSF matfile...',true);
            [PSFfile,PSFpath] = uigetfile([app.selpath,'\*.mat']);
            try
                app.PSFFilePathLabel.Text =  [PSFpath,PSFfile];
                app.readylabel_signal('PSF matfile acquired!',false);
            catch ME
                app.readylabel_signal(ME.message,true);
            end
            
        end

        % Button pushed function: FLFMFileButton
        function FLFMFileButtonPushed(app, event)
            app.readylabel_signal('Acquiring FLFM image folder...',true);
            FLFMpath = uigetdir(app.selpath);
            try
                app.FLFMFilePathLabel.Text = FLFMpath;
                app.readylabel_signal('FLFM image folder acquired!',false);
            catch ME
              app.readylabel_signal(ME.message,true);
            end
        end

        % Button pushed function: ReconstructionFileButton
        function ReconstructionFileButtonPushed(app, event)
            app.readylabel_signal('Getting reconstruction path...',true);
            Reconpath = uigetdir(app.selpath);
            try
                app.ReconstructionFilePathLabel.Text = Reconpath;
                app.readylabel_signal('Reconstruction path got!',false);
            catch ME
                app.readylabel_signal(ME.message,true);
            end
        end

        % Button pushed function: RLDeconvolutionButton
        function RLDeconvolutionButtonPushed(app, event)
            clc;
            app.readylabel_signal(['Reconstructing (',num2str(app.IterNumEditField.Value),' iters)...'],true);
            Centers = [app.RowTLEditField.Value,app.ColTLEditField.Value;
                       app.RowTREditField.Value,app.ColTREditField.Value;
                       app.RowBtmEditField.Value,app.ColBtmEditField.Value];
            switch app.ModeDropDown.Value
                case 'Wave Optics Conventional'
                    Mode = "wave";
                case 'Wave Optics Full GPU'
                    Mode = "fast";
                case 'Hybrid with Ray Optics'
                    Mode = "hybrid";
                case 'Shifted Hybrid'
                    Mode = "shifted";
            end
            app.readylabel_signal([char(Mode),': ',app.ModeDropDown.Value],true);
            Decon3D(app.PSFFilePathLabel.Text,... 
                    app.FLFMFilePathLabel.Text,...
                    app.ReconstructionFilePathLabel.Text,...
                    app.IterNumEditField.Value,...
                    Centers,...
                    app.dcentpos,...
                    Mode,...
                    app.ReplaceexistingfilesCheckBox.Value,...
                    app.ReadyLabel,...
                    app.UIAxes);
%                     app.CutShiftEditField.Value,...
%                     app.CropHeightEditField.Value,...
%                     app.CropWidthEditField.Value,...

            app.readylabel_signal('Reconstruction completed! File saved!',false);
        end

        % Callback function
        function OpenimageDirectoryButtonPushed(app, event)
            if app.initialized ==0
                try
                    app.savepath = 'Z:\Xuanwen\FLFMuf\ExpData';
                catch
                    app.savepath = '.\';
                end
            end
            savepath0 = uigetdir(app.savepath);
            if length(char(savepath0))~=1
                app.savepath = savepath0;
                delete(app.Tree2.Children)
                app.selpath = savepath0;
                try
                    % disp(['Save Directory: ',app.savepath])
                    S = split(app.savepath,'\');
                    uitreepath = uitreenode(app.Tree2,'Text',char(S{end}));
                    subfolder = dir(app.savepath);
                    for i = 3:length(subfolder)
                        uitreenode(uitreepath,'Text',subfolder(i).name);
                    end
                    expand(app.Tree2,'all')
                catch
                    
                end
            end
            app.initialized = 1;
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            disp(selectedNodes.Text);
            selectedNodes_text = selectedNodes.Text;
            if string(selectedNodes_text(end-3:end))==".tif" 
                app.readylabel_signal('Loading image...',true);
                tifname2open = [app.selpath,'/',selectedNodes_text];
                app.preimg = imread(tifname2open);
                app.imgdatatype = string(class(app.preimg));
                app.preimg = double(app.preimg);
                app.preimg = app.preimg/max(max(app.preimg));
                app.Label.Text = num2str(min(size(app.preimg,1),size(app.preimg,2)));
                app.himg = imshow(app.preimg,'parent', app.UIAxes,'Colormap',app.clmap);
                app.readylabel_signal(selectedNodes_text,false);
            else
                app.readylabel_signal([selectedNodes_text,' is not an image!'],false);
            end
        end

        % Selection changed function: OptionsButtonGroup
        function OptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptionsButtonGroup.SelectedObject;
            if string(selectedButton.Text) == "BKG Offset"
                app.BKGOffsetEditField.Enable = true;
                app.LOADButton.Enable = false;
                app.OffsetpathLabel.Enable = false;
            else
                app.BKGOffsetEditField.Enable = false;
                app.LOADButton.Enable = true;
                app.OffsetpathLabel.Enable = true;
            end
        end

        % Value changed function: PreviewoffsetsubtractionCheckBox
        function PreviewoffsetsubtractionCheckBoxValueChanged(app, event)
            value = app.PreviewoffsetsubtractionCheckBox.Value;
            if value
                app.offsetsubtraction();
            else
               app.show_original_img();
            end
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            app.readylabel_signal('Loading offset file...',true);
            [Offsetfile,Offsetpath] = uigetfile([app.selpath,'\*.mat']);
            try
                app.OffsetpathLabel.Text =  [Offsetpath,Offsetfile];
                load(app.OffsetpathLabel.Text,'Offset');
                app.OffsetMat = Offset;
                app.readylabel_signal('Offset file loaded!',false);
            catch ME
                app.readylabel_signal(ME.message,true);
            end
        end

        % Button pushed function: SUBTRACTButton
        function SUBTRACTButtonPushed(app, event)
            if app.SubtractentirefolderCheckBox.Value
                tif2open = dir([app.selpath,'/*.tif']);
                disp(length(tif2open))
                for ii = 1:1:length(tif2open)
                    app.preimg = double(imread([app.selpath,'/',tif2open(ii).name]));
                    if string(app.OptionsButtonGroup.SelectedObject.Text)=="BKG Offset"
                        app.preimg = (app.preimg - app.OffsetEditField.Value).*double((app.preimg - app.OffsetEditField.Value)>=0);
                        imgout = app.imtypeconvert(app.preimg);
                        app.imshow_norm(app.preimg);
                        app.readylabel_signal('Offset subtraction done!',false);
                    else
                        try
                            app.preimg = (app.preimg - app.OffsetMat).*double((app.preimg - app.OffsetMat)>=0);
                            imgout = app.imtypeconvert(app.preimg);
                            app.imshow_norm(app.preimg);
                            app.readylabel_signal('Offset subtraction done!',false);
                        catch ME
                            app.readylabel_signal(ME.message,true);
                        end
                    end
                    app.readylabel_signal('Saving image...',true);
                    UpperSave(app.selpath,tif2open(ii).name,imgout,'offsetsub');
                    app.readylabel_signal(['Image [',tif2open(ii).name,'] saved!'],false);
                end
            else
                selectedNodes_text = app.Tree.SelectedNodes.Text;
                if string(selectedNodes_text(end-3:end))==".tif"                    
                    app.readylabel_signal('Subtracting offset...',true);
                    tifname2open = [app.selpath,'/',selectedNodes_text];
                    app.preimg = double(imread(tifname2open));
                    if string(app.OptionsButtonGroup.SelectedObject.Text)=="BKG Offset"
                        app.preimg = (app.preimg - app.OffsetEditField.Value).*double((app.preimg - app.OffsetEditField.Value)>=0);
                        imgout = app.imtypeconvert(app.preimg);
                        app.imshow_norm(app.preimg);
                        app.readylabel_signal('Offset subtraction done!',false);
                    else
                        try
                            app.preimg = (app.preimg - app.OffsetMat).*double((app.preimg - app.OffsetMat)>=0);
                            imgout = app.imtypeconvert(app.preimg);
                            app.imshow_norm(app.preimg);
                            app.readylabel_signal('Offset subtraction done!',false);
                        catch ME
                            app.readylabel_signal(ME.message,true);
                        end
                    end
                    app.readylabel_signal('Saving image...',true);
                    UpperSave(app.selpath,selectedNodes_text,imgout,'offsetsub');
                    app.readylabel_signal('Image saved!',false);
                else
                    app.readylabel_signal('Image previewing: no image selected!',false);
                end  
            end
        end

        % Value changed function: PreviewresultsCheckBox
        function PreviewresultsCheckBoxValueChanged(app, event)
            value = app.PreviewresultsCheckBox.Value;
            if value
                if ~exist("ij.IJ","class")
                    app.readylabel_signal('Opening ImageJ...',true);
                    ImageJ;
                end
                selectedNodes_text = app.Tree.SelectedNodes.Text;
                if string(selectedNodes_text(end-3:end))==".tif"
                    app.readylabel_signal('Subtracting offset...',true);
                    tif2open = [app.selpath,'/',selectedNodes_text];
                    app.preimg = double(imread(tif2open));
                    preimg2IJM = copytoImg (app.preimg);
                    net.imglib2.img.display.imagej.ImageJFunctions.show(preimg2IJM);
                    if app.LightbackgroundCheckBox.Value
                        Lightbackground = 'light';
                    else
                        Lightbackground = '';
                    end
                    if app.UseparaboloidrollingCheckBox.Value
                        Useparaboloidrolling = 'sliding';
                    else
                        Useparaboloidrolling = '';
                    end
                    if app.UsepresmoothingCheckBox.Value
                        Usepresmoothing = '';
                    else
                        Usepresmoothing = 'disable';
                    end
                    ij.IJ.run("Subtract Background...", string(['rolling=',num2str(app.RadiusEditField.Value),' ',...
                                Lightbackground,' ',Useparaboloidrolling,' ',Usepresmoothing]));
                    ij.IJ.saveAs("Tiff",'.\rollingpreview.tif');
                    ij.IJ.run("Close","");
                    app.preimg = imread('.\rollingpreview.tif');
                    delete('.\rollingpreview.tif')
                    app.imshow_norm(app.preimg)
                    app.readylabel_signal('Image rolling-ball subtracted!',false);
                else
                    app.readylabel_signal('Image previewing: no image selected!',false);
                end
            else
               app.show_original_img();
            end
        end

        % Button pushed function: SaveRolledImageButton
        function SaveRolledImageButtonPushed(app, event)
            if ~exist("ij.IJ","class")
                app.readylabel_signal('Opening ImageJ...',true);
                ImageJ;
            end
            if app.RollingentirefolderCheckBox.Value
                tif2open = dir([app.selpath,'/*.tif']);
                disp(length(tif2open))
                mkdir([app.selpath,'_offsetsub\'])
                for ii = 1:1:length(tif2open)
                    app.preimg = imread([app.selpath,'/',tif2open(ii).name]);
                    preimg2IJM = copytoImg (app.preimg);
                    net.imglib2.img.display.imagej.ImageJFunctions.show(preimg2IJM);
                    if app.LightbackgroundCheckBox.Value
                        Lightbackground = 'light';
                    else
                        Lightbackground = '';
                    end
                    if app.UseparaboloidrollingCheckBox.Value
                        Useparaboloidrolling = 'sliding';
                    else
                        Useparaboloidrolling = '';
                    end
                    if app.UsepresmoothingCheckBox.Value
                        Usepresmoothing = '';
                    else
                        Usepresmoothing = 'disable';
                    end
                    ij.IJ.run("Subtract Background...", string(['rolling=',num2str(app.RadiusEditField.Value),' ',...
                                Lightbackground,' ',Useparaboloidrolling,' ',Usepresmoothing]));
                    app.readylabel_signal('Saving image...',true);
                    ij.IJ.saveAs("Tiff",[app.selpath,'_offsetsub\',tif2open(ii).name]);
                    ij.IJ.run("Close","");
                    app.readylabel_signal(['Image [',tif2open(ii).name,'] saved!'],false);                    
                end
            else
                selectedNodes_text = app.Tree.SelectedNodes.Text;
                if string(selectedNodes_text(end-3:end))==".tif"
                    app.readylabel_signal('Subtracting offset...',true);
                    tif2open = [app.selpath,'/',selectedNodes_text];
                    app.preimg = imread(tif2open);
                    preimg2IJM = copytoImg (app.preimg);
                    net.imglib2.img.display.imagej.ImageJFunctions.show(preimg2IJM);
                    if app.LightbackgroundCheckBox.Value
                        Lightbackground = 'light';
                    else
                        Lightbackground = '';
                    end
                    if app.UseparaboloidrollingCheckBox.Value
                        Useparaboloidrolling = 'sliding';
                    else
                        Useparaboloidrolling = '';
                    end
                    if app.UsepresmoothingCheckBox.Value
                        Usepresmoothing = '';
                    else
                        Usepresmoothing = 'disable';
                    end
                    ij.IJ.run("Subtract Background...", string(['rolling=',num2str(app.RadiusEditField.Value),' ',...
                                Lightbackground,' ',Useparaboloidrolling,' ',Usepresmoothing]));
                    app.readylabel_signal('Saving image...',true);
                    ij.IJ.saveAs("Tiff",[app.selpath,'_offsetsub\',selectedNodes_text]);
                    ij.IJ.run("Close","");
                    app.readylabel_signal('Image saved!',false);
                else
                    app.readylabel_signal('Image previewing: no image selected!',false);
                end
            end
        end

        % Button pushed function: LoadfileforbothoffsetandgainButton
        function LoadfileforbothoffsetandgainButtonPushed(app, event)
            app.readylabel_signal('Loading camera calibration file...',true);
            [Offsetfile,Offsetpath] = uigetfile([app.selpath,'\*.mat']);
            try
                load([Offsetpath,Offsetfile],'Offset');
                load([Offsetpath,Offsetfile],'Gain');
                app.OffsetMat = Offset;
                app.GainMat = Gain;
                app.readylabel_signal('Camera calibration file loaded!',false);
                app.LoadMatButton_2.Value = true;
                app.LoadMatButton_3.Value = true;
                
                app.OffsetEditField.Enable = false;
                app.LOADButton_2.Enable = true;
                app.OffsetpathLabel_2.Enable = true;
                app.GainEditField.Enable = false;
                app.LOADButton_3.Enable = true;
                app.GainpathLabel.Enable = true;
                
                app.OffsetpathLabel_2.Text = [Offsetpath,Offsetfile];
                app.GainpathLabel.Text = [Offsetpath,Offsetfile];
            catch ME
                app.readylabel_signal(ME.message,true);
            end
        end

        % Selection changed function: OffsetsettingsButtonGroup
        function OffsetsettingsButtonGroupSelectionChanged(app, event)
            selectedButton = app.OffsetsettingsButtonGroup.SelectedObject;
            if string(selectedButton.Text) == "Manual"
                app.OffsetEditField.Enable = true;
                app.LOADButton_2.Enable = false;
                app.OffsetpathLabel_2.Enable = false;
            else
                app.OffsetMat = [];
                app.OffsetEditField.Enable = false;
                app.LOADButton_2.Enable = true;
                app.OffsetpathLabel_2.Enable = true;
            end
        end

        % Button pushed function: LOADButton_2
        function LOADButton_2Pushed(app, event)
            app.readylabel_signal('Loading offset file...',true);
            [Offsetfile,Offsetpath] = uigetfile([app.selpath,'\*.mat']);
            try
                app.OffsetpathLabel_2.Text =  [Offsetpath,Offsetfile];
                load(app.OffsetpathLabel_2.Text,'offset');
                app.OffsetMat = offset;
                app.readylabel_signal('Offset file loaded!',false);
            catch ME
                app.readylabel_signal(ME.message,true);
            end
            disp(length(app.OffsetMat))
        end

        % Selection changed function: GainsettingsButtonGroup
        function GainsettingsButtonGroupSelectionChanged(app, event)
            selectedButton = app.GainsettingsButtonGroup.SelectedObject;
            if string(selectedButton.Text) == "Manual"
                app.GainEditField.Enable = true;
                app.LOADButton_3.Enable = false;
                app.GainpathLabel.Enable = false;
            else
                app.GainMat = [];
                app.GainEditField.Enable = false;
                app.LOADButton_3.Enable = true;
                app.GainpathLabel.Enable = true;
            end
        end

        % Button pushed function: LOADButton_3
        function LOADButton_3Pushed(app, event)
            app.readylabel_signal('Loading gain file...',true);
            [Gainfile,Gainpath] = uigetfile([app.selpath,'\*.mat']);
            try
                app.GainpathLabel.Text =  [Gainpath,Gainfile];
                load(app.GainpathLabel.Text,'gain');
                app.GainMat = gain;
                app.readylabel_signal('Gain file loaded!',false);
            catch ME
                app.readylabel_signal(ME.message,true);
            end
        end

        % Button pushed function: ACsNDenoiseButton
        function ACsNDenoiseButtonPushed(app, event)
            addpath([fileparts(mfilename('fullpath')),'\ACsN\']);
            addpath([fileparts(mfilename('fullpath')),'\ACsN\ACsN_code\']);
            addpath([fileparts(mfilename('fullpath')),'\ACsN\ACsN_code\Functions\']);
            if app.ProcessSelectedOneButton.Value
                selectedNodes_text = app.Tree.SelectedNodes.Text;
                if string(selectedNodes_text(end-3:end))==".tif"
                    app.readylabel_signal('Denoising image with ACsN algorithm...',true);
                    try
                        raw = double(loadtiff([app.selpath,'/',selectedNodes_text]));
                        % -------------------------------------------------------
                        if string(app.OffsetsettingsButtonGroup.SelectedObject.Text) == "Manual"
                            app.OffsetMat = app.OffsetEditField.Value;
                        end                    
                        if string(app.GainsettingsButtonGroup.SelectedObject.Text) == "Manual"
                            app.GainMat = app.GainEditField.Value;
                        end
                        % -------------------------------------------------------
                        % resize the matrix
                        [rawrow,rawcol] = size(raw);
                        disp([rawrow,rawcol])
                        if length(app.OffsetMat)~=1
                            [offsetraw,offsetcol] = size(app.OffsetMat);
    %                         disp(round(offsetraw/2-rawrow/2):round(offsetraw/2-rawrow/2)+rawrow-1)
                            app.OffsetMat = app.OffsetMat(round(offsetraw/2-rawrow/2)+1:round(offsetraw/2-rawrow/2)+rawrow,...
                                round(offsetcol/2-rawcol/2)+1:round(offsetcol/2-rawcol/2)+rawcol);
                            
                        end
                        if length(app.GainMat)~=1
                            [gainraw,gaincol] = size(app.GainMat);
                            app.GainMat = app.GainMat(round(gainraw/2-rawrow/2)+1:round(gainraw/2-rawrow/2)+rawrow,...
                                round(gaincol/2-rawcol/2)+1:round(gaincol/2-rawcol/2)+rawcol);
                        end
                        % -------------------------------------------------------
                        disp([length(app.OffsetMat),length(app.GainMat)])
                        acsn  = ACSN(raw,app.NumAperEditField.Value,...
                            app.WavelengthEditField.Value,...
                            app.PixelSizeEditField.Value,...
                            'Offset',app.OffsetMat,'Gain',app.GainMat,'Weight',1);
                        
                        app.imshow_norm(acsn);
                        app.preimg = app.imtypeconvert(acsn);
                        app.SaveACsNedImageButton.Enable = true;
                        app.readylabel_signal('ACsN Denoising completed!',false);
                    catch ME
                        app.readylabel_signal(ME.message,true);
                        rethrow(ME)
                    end
                else
                    app.readylabel_signal('Image previewing: no image selected!',false);
                end
            elseif app.ProcessEntireDirectoryButton.Value
                filelist = dir(app.selpath);
                for ifile = 3:1:length(filelist)
                    selectedNodes_text = [app.selpath,'\',filelist(ifile).name];
                    if string(selectedNodes_text(end-3:end))==".tif"
                        app.readylabel_signal(['Denoising image with ACsN algorithm... ',filelist(ifile).name],true);
                        try
%                             disp(selectedNodes_text)
                            raw = double(loadtiff(selectedNodes_text));
                            % -------------------------------------------------------
                            if string(app.OffsetsettingsButtonGroup.SelectedObject.Text) == "Manual"
                                app.OffsetMat = app.OffsetEditField.Value;
                            end
                            if string(app.GainsettingsButtonGroup.SelectedObject.Text) == "Manual"
                                app.GainMat = app.GainEditField.Value;
                            end
                            % -------------------------------------------------------
                            % resize the matrix
                            [rawrow,rawcol] = size(raw);
                            disp([rawrow,rawcol])
                            if length(app.OffsetMat)~=1
                                [offsetraw,offsetcol] = size(app.OffsetMat);
                                %                         disp(round(offsetraw/2-rawrow/2):round(offsetraw/2-rawrow/2)+rawrow-1)
                                app.OffsetMat = app.OffsetMat(round(offsetraw/2-rawrow/2)+1:round(offsetraw/2-rawrow/2)+rawrow,...
                                    round(offsetcol/2-rawcol/2)+1:round(offsetcol/2-rawcol/2)+rawcol);

                            end
                            if length(app.GainMat)~=1
                                [gainraw,gaincol] = size(app.GainMat);
                                app.GainMat = app.GainMat(round(gainraw/2-rawrow/2)+1:round(gainraw/2-rawrow/2)+rawrow,...
                                    round(gaincol/2-rawcol/2)+1:round(gaincol/2-rawcol/2)+rawcol);
                            end
                            % -------------------------------------------------------
                            disp([length(app.OffsetMat),length(app.GainMat)])
                            acsn  = ACSN(raw,app.NumAperEditField.Value,...
                                app.WavelengthEditField.Value,...
                                app.PixelSizeEditField.Value,...
                                'Offset',app.OffsetMat,'Gain',app.GainMat,'Weight',1);

%                             app.imshow_norm(acsn);
                            app.preimg = app.imtypeconvert(acsn);
                            app.readylabel_signal('Saving image...',true);
                            UpperSave(app.selpath,filelist(ifile).name,app.preimg,'acsn');
                            app.readylabel_signal('Image saved!',false);
                            
                        catch ME
                            app.readylabel_signal(ME.message,true);
                            rethrow(ME)
                        end
                    else
                        app.readylabel_signal('Image previewing: no image selected!',false);
                    end
                end
                app.readylabel_signal('ACsN Denoising completed!',false);
            end
        end

        % Button pushed function: SaveACsNedImageButton
        function SaveACsNedImageButtonPushed(app, event)
            selectedNodes_text = app.Tree.SelectedNodes.Text;
            if string(selectedNodes_text(end-3:end))==".tif"
                app.readylabel_signal('Saving image...',true);
                UpperSave(app.selpath,selectedNodes_text,app.preimg,'acsn');
                app.readylabel_signal('Image saved!',false);
            else
                app.readylabel_signal('Image previewing: no image selected!',false);
            end
        end

        % Button pushed function: ShowOriginalImageButton
        function ShowOriginalImageButtonPushed(app, event)
            app.show_original_img();
            app.SaveACsNedImageButton.Enable = false;
        end

        % Value changed function: RowTLEditField
        function RowTLEditFieldValueChanged(app, event)
            value = app.RowTLEditField.Value;
            app.RowTREditField.Value = value;
            app.RowBtmEditField.Value = value + round(app.PitchSizeEditField.Value/2*sqrt(3));
        end

        % Value changed function: ColTLEditField
        function ColTLEditFieldValueChanged(app, event)
            value = app.ColTLEditField.Value;
            app.ColTREditField.Value = value + app.PitchSizeEditField.Value;
            app.ColBtmEditField.Value = value + round(app.PitchSizeEditField.Value/2);
        end

        % Button pushed function: GrayButton
        function GrayButtonPushed(app, event)
            app.clmap = gray(256);
            app.show_colormap();
            app.imshow_norm(app.preimg);
        end

        % Button pushed function: MagentaButton
        function MagentaButtonPushed(app, event)
            app.clmap = gray(256);
            app.clmap(:,2)=0;
            app.show_colormap();
            app.imshow_norm(app.preimg);
        end

        % Button pushed function: GreenButton
        function GreenButtonPushed(app, event)
            app.clmap = gray(256);
            app.clmap(:,[1 3])=0;
            app.show_colormap();
            app.imshow_norm(app.preimg);
        end

        % Button pushed function: CyanButton
        function CyanButtonPushed(app, event)
            app.clmap = gray(256);
            app.clmap(:,1)=0;
            app.show_colormap();
            app.imshow_norm(app.preimg);
        end

        % Button pushed function: InfernoButton
        function InfernoButtonPushed(app, event)
            app.clmap = inferno(256);
            app.show_colormap();
            app.imshow_norm(app.preimg);
        end

        % Button pushed function: JetButton
        function JetButtonPushed(app, event)
            app.clmap = jet(256);
            app.show_colormap();
            app.imshow_norm(app.preimg);
        end

        % Button pushed function: RainbowButton
        function RainbowButtonPushed(app, event)
            app.clmap = rainbow(256);
            app.show_colormap();
            app.imshow_norm(app.preimg);
        end

        % Button pushed function: DisplayStack3DButton
        function DisplayStack3DButtonPushed(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            selectedNodes_text = selectedNodes.Text;
            if string(selectedNodes_text(end-3:end))==".tif"
                app.readylabel_signal('Drawing color stack...',true);
                tifname2open = [app.selpath,'/',selectedNodes_text];
                beginind = app.StartIndexSpinner.Value;
                endind = app.EndIndexSpinner.Value;
                if endind>beginind
                    setmid = round((beginind+endind)/2)+app.IntensityOffsetEditField.Value;
                    setleftwid=app.IntDleftwidthEditField.Value;
                    setrightwid = app.IntDrightwidthEditField.Value;
                    intscale = ((beginind:1:endind)<=setmid)*setleftwid+((beginind:1:endind)>setmid)*setrightwid;
                    disp(intscale)

                    im = double(tiffreadVolume(tifname2open,"PixelRegion",...
                        {[app.rowstart.Value app.rowend.Value],[app.colstart.Value app.colend.Value],[beginind endind]}));
                    im = im./max(im,[],[1,2]);
                    clmapmat1 = reshape(app.clmap(round(((beginind:1:endind)-beginind)/(endind-beginind)*255)+1,1).*...
                        intscale((beginind:1:endind)-beginind+1)',1,1,[]);
                    imclstack = sum(im.* clmapmat1,3);
                    clmapmat2 = reshape(app.clmap(round(((beginind:1:endind)-beginind)/(endind-beginind)*255)+1,2).*...
                        intscale((beginind:1:endind)-beginind+1)',1,1,[]);
                    imclstack = cat(3,imclstack,sum(im.* clmapmat2,3));
                    clmapmat3 = reshape(app.clmap(round(((beginind:1:endind)-beginind)/(endind-beginind)*255)+1,3).*...
                        intscale((beginind:1:endind)-beginind+1)',1,1,[]);
                    imclstack = cat(3,imclstack,sum(im.* clmapmat3,3));
                    app.preimg = drawScalebar(uint8(imclstack/max(imclstack(:))*256*app.IntensityGainEditField.Value),...
                        app.LengthEditField.Value,app.barPixelsizeEditField.Value,char(app.PositionDropDown.Value));
                    imshow(app.preimg,'Parent',app.UIAxes)

                    app.readylabel_signal(selectedNodes_text,false);
                else
                    app.readylabel_signal('The Start index should be no larger than the end index!',true);
                end
            else
                app.readylabel_signal([selectedNodes_text,' is not an image!'],true);
            end
        end

        % Button pushed function: FitPSFElementalDisplacementsButton
        function FitPSFElementalDisplacementsButtonPushed(app, event)
            load(app.PSFFilePathLabel.Text,'FLFPSF');
            FLFPSFnorm = FLFPSF./max(FLFPSF,[],[1 2]);
            PSF1 = FLFPSFnorm(app.RowTLEditField.Value-round(app.PitchSizeEditField.Value/2):app.RowTLEditField.Value+round(app.PitchSizeEditField.Value/2),...
                              app.ColTLEditField.Value-round(app.PitchSizeEditField.Value/2):app.ColTLEditField.Value+round(app.PitchSizeEditField.Value/2),:);
            PSF2 = FLFPSFnorm(app.RowTREditField.Value-round(app.PitchSizeEditField.Value/2):app.RowTREditField.Value+round(app.PitchSizeEditField.Value/2),...
                              app.ColTREditField.Value-round(app.PitchSizeEditField.Value/2):app.ColTREditField.Value+round(app.PitchSizeEditField.Value/2),:);
            PSF3 = FLFPSFnorm(app.RowBtmEditField.Value-round(app.PitchSizeEditField.Value/2):app.RowBtmEditField.Value+round(app.PitchSizeEditField.Value/2),...
                              app.ColBtmEditField.Value-round(app.PitchSizeEditField.Value/2):app.ColBtmEditField.Value+round(app.PitchSizeEditField.Value/2),:);
            [X,Y] = meshgrid(1:1:size(PSF1,2),1:1:size(PSF1,1));
            circmask = double(sqrt((X-251).^2 + (Y-251).^2)<=250);
            PSF1 = circmask.*PSF1;
            PSF2 = circmask.*PSF2;
            PSF3 = circmask.*PSF3;

            PSF1_cents = [squeeze(sum(PSF1.*X,[1,2])./sum(PSF1,[1,2])),...
                squeeze(sum(PSF1.*Y,[1,2])./sum(PSF1,[1,2]))];
            PSF1_cents = round(PSF1_cents-PSF1_cents(round(length(PSF1_cents)/2),:));
            p1 = polyfit((1:length(PSF1_cents))-round(length(PSF1_cents)/2),PSF1_cents(:,1),2);
            p2 = polyfit((1:length(PSF1_cents))-round(length(PSF1_cents)/2),PSF1_cents(:,2),2);

            PSF2_cents = [squeeze(sum(PSF2.*X,[1,2])./sum(PSF2,[1,2])),...
                squeeze(sum(PSF2.*Y,[1,2])./sum(PSF2,[1,2]))];
            PSF2_cents = round(PSF2_cents-PSF2_cents(round(length(PSF2_cents)/2),:));
            p3 = polyfit((1:length(PSF2_cents))-round(length(PSF2_cents)/2),PSF2_cents(:,1),2);
            p4 = polyfit((1:length(PSF2_cents))-round(length(PSF2_cents)/2),PSF2_cents(:,2),2);

            PSF3_cents = [squeeze(sum(PSF3.*X,[1,2])./sum(PSF3,[1,2])),...
                squeeze(sum(PSF3.*Y,[1,2])./sum(PSF3,[1,2]))];
            PSF3_cents = round(PSF3_cents-PSF3_cents(round(length(PSF3_cents)/2),:));
            p5 = polyfit((1:length(PSF3_cents))-round(length(PSF3_cents)/2),PSF3_cents(:,1),2);
            p6 = polyfit((1:length(PSF3_cents))-round(length(PSF3_cents)/2),PSF3_cents(:,2),2);

            app.dcentpos = [polyval(p2,((1:length(PSF3_cents))-round(length(PSF3_cents)/2)))',...
                            polyval(p1,((1:length(PSF3_cents))-round(length(PSF3_cents)/2)))',...
                            polyval(p4,((1:length(PSF3_cents))-round(length(PSF3_cents)/2)))',...
                            polyval(p3,((1:length(PSF3_cents))-round(length(PSF3_cents)/2)))',...
                            polyval(p6,((1:length(PSF3_cents))-round(length(PSF3_cents)/2)))',...
                            polyval(p5,((1:length(PSF3_cents))-round(length(PSF3_cents)/2)))'];

            figure(233),plot(app.dcentpos);
        end

        % Close request function: HRFLFMDP
        function HRFLFMDPCloseRequest(app, event)
            try
                ij.IJ.run("Quit","");
            catch

            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HRFLFMDP = uifigure('Visible', 'off');
            app.HRFLFMDP.Name = 'High Resolution Fourier Light Field Microscopy Data Processing';
            app.HRFLFMDP.CloseRequestFcn = createCallbackFcn(app, @HRFLFMDPCloseRequest, true);
            app.FileMenu = uimenu(app.HRFLFMDP);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.HRFLFMDP);
            app.EditMenu.Text = 'Edit';
            app.AboutMenu = uimenu(app.HRFLFMDP);
            app.AboutMenu.Text = 'About';
            app.AboutHRFLFMDPMenu = uimenu(app.AboutMenu);
            app.AboutHRFLFMDPMenu.Text = 'About HRFLFMDP...';
            app.GridLayout = uigridlayout(app.HRFLFMDP);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTickLabel = '';
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.ReadyLabel = uilabel(app.GridLayout);
            app.ReadyLabel.Text = 'Ready!';
            app.OpenDirectoryButton = uibutton(app.GridLayout, 'push');
            app.OpenDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDirectoryButtonPushed, true);
            app.OpenDirectoryButton.Text = 'Open Directory';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.BKGSubTab = uitab(app.TabGroup);
            app.BKGSubTab.Title = 'BKGSub';
            app.GridLayout5 = uigridlayout(app.BKGSubTab);
            app.BackgroundsubtractionbyoffsetLabel = uilabel(app.GridLayout5);
            app.BackgroundsubtractionbyoffsetLabel.Text = ' Background subtraction by offset';
            app.OptionsButtonGroup = uibuttongroup(app.GridLayout5);
            app.OptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionsButtonGroupSelectionChanged, true);
            app.OptionsButtonGroup.Title = 'Options';
            app.BKGOffsetButton = uiradiobutton(app.OptionsButtonGroup);
            app.BKGOffsetButton.Text = 'BKG Offset';
            app.BKGOffsetButton.Value = true;
            app.LoadMatButton = uiradiobutton(app.OptionsButtonGroup);
            app.LoadMatButton.Text = 'Load Mat';
            app.BKGOffsetEditField = uieditfield(app.OptionsButtonGroup, 'numeric');
            app.BKGOffsetEditField.Value = 100;
            app.LOADButton = uibutton(app.OptionsButtonGroup, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.OffsetpathLabel = uilabel(app.GridLayout5);
            app.OffsetpathLabel.Text = 'Offset path';
            app.SUBTRACTButton = uibutton(app.GridLayout5, 'push');
            app.SUBTRACTButton.ButtonPushedFcn = createCallbackFcn(app, @SUBTRACTButtonPushed, true);
            app.SUBTRACTButton.Text = 'SUBTRACT';
            app.BackgroundsubtractionbyrollingballalgorithmLabel = uilabel(app.GridLayout5);
            app.BackgroundsubtractionbyrollingballalgorithmLabel.Text = ' Background subtraction by rolling ball algorithm';
            app.LightbackgroundCheckBox = uicheckbox(app.GridLayout5);
            app.LightbackgroundCheckBox.Text = 'Light background';
            app.UseparaboloidrollingCheckBox = uicheckbox(app.GridLayout5);
            app.UseparaboloidrollingCheckBox.Text = 'Use paraboloid rolling';
            app.UsepresmoothingCheckBox = uicheckbox(app.GridLayout5);
            app.UsepresmoothingCheckBox.Text = 'Use presmoothing';
            app.RadiusLabel = uilabel(app.GridLayout5);
            app.RadiusLabel.Text = 'Radius:';
            app.RadiusEditField = uieditfield(app.GridLayout5, 'numeric');
            app.RadiusEditField.Value = 10;
            app.PreviewresultsCheckBox = uicheckbox(app.GridLayout5);
            app.PreviewresultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreviewresultsCheckBoxValueChanged, true);
            app.PreviewresultsCheckBox.Text = 'Preview results';
            app.SaveRolledImageButton = uibutton(app.GridLayout5, 'push');
            app.SaveRolledImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRolledImageButtonPushed, true);
            app.SaveRolledImageButton.Text = 'Save Rolled Image';
            app.PreviewoffsetsubtractionCheckBox = uicheckbox(app.GridLayout5);
            app.PreviewoffsetsubtractionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreviewoffsetsubtractionCheckBoxValueChanged, true);
            app.PreviewoffsetsubtractionCheckBox.Text = 'Preview offset subtraction';
            app.SubtractentirefolderCheckBox = uicheckbox(app.GridLayout5);
            app.SubtractentirefolderCheckBox.Text = 'Subtract entire folder';
            app.RollingentirefolderCheckBox = uicheckbox(app.GridLayout5);
            app.RollingentirefolderCheckBox.Text = 'Rolling entire folder';
            app.ACsNTab = uitab(app.TabGroup);
            app.ACsNTab.Title = 'ACsN';
            app.GridLayout4 = uigridlayout(app.ACsNTab);
            app.NumAperEditFieldLabel = uilabel(app.GridLayout4);
            app.NumAperEditFieldLabel.Text = 'Num. Aper.';
            app.NumAperEditField = uieditfield(app.GridLayout4, 'numeric');
            app.NumAperEditField.Value = 1.45;
            app.WavelengthEditFieldLabel = uilabel(app.GridLayout4);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.GridLayout4, 'numeric');
            app.WavelengthEditField.Value = 680;
            app.nmLabel = uilabel(app.GridLayout4);
            app.nmLabel.Text = ' nm';
            app.OffsetpathLabel_2 = uilabel(app.GridLayout4);
            app.OffsetpathLabel_2.Text = 'Offset path';
            app.SaveACsNedImageButton = uibutton(app.GridLayout4, 'push');
            app.SaveACsNedImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveACsNedImageButtonPushed, true);
            app.SaveACsNedImageButton.Enable = 'off';
            app.SaveACsNedImageButton.Text = 'Save ACsNed Image';
            app.OffsetsettingsButtonGroup = uibuttongroup(app.GridLayout4);
            app.OffsetsettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OffsetsettingsButtonGroupSelectionChanged, true);
            app.OffsetsettingsButtonGroup.Title = 'Offset settings';
            app.ManualButton = uiradiobutton(app.OffsetsettingsButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.LoadMatButton_2 = uiradiobutton(app.OffsetsettingsButtonGroup);
            app.LoadMatButton_2.Text = 'Load Mat';
            app.OffsetEditField = uieditfield(app.OffsetsettingsButtonGroup, 'numeric');
            app.OffsetEditField.Value = 100;
            app.LOADButton_2 = uibutton(app.OffsetsettingsButtonGroup, 'push');
            app.LOADButton_2.ButtonPushedFcn = createCallbackFcn(app, @LOADButton_2Pushed, true);
            app.LOADButton_2.Text = 'LOAD';
            app.GainsettingsButtonGroup = uibuttongroup(app.GridLayout4);
            app.GainsettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GainsettingsButtonGroupSelectionChanged, true);
            app.GainsettingsButtonGroup.Title = 'Gain settings';
            app.ManualButton_2 = uiradiobutton(app.GainsettingsButtonGroup);
            app.ManualButton_2.Text = 'Manual';
            app.ManualButton_2.Value = true;
            app.LoadMatButton_3 = uiradiobutton(app.GainsettingsButtonGroup);
            app.LoadMatButton_3.Text = 'Load Mat';
            app.GainEditField = uieditfield(app.GainsettingsButtonGroup, 'numeric');
            app.GainEditField.Value = 1;
            app.LOADButton_3 = uibutton(app.GainsettingsButtonGroup, 'push');
            app.LOADButton_3.ButtonPushedFcn = createCallbackFcn(app, @LOADButton_3Pushed, true);
            app.LOADButton_3.Text = 'LOAD';
            app.GainpathLabel = uilabel(app.GridLayout4);
            app.GainpathLabel.Text = 'Gain path';
            app.Image = uiimage(app.GridLayout4);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'acsn.jpg');
            app.ACsNLabel = uilabel(app.GridLayout4);
            app.ACsNLabel.Text = 'ACsN';
            app.LoadfileforbothoffsetandgainButton = uibutton(app.GridLayout4, 'push');
            app.LoadfileforbothoffsetandgainButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileforbothoffsetandgainButtonPushed, true);
            app.LoadfileforbothoffsetandgainButton.Text = 'Load file for both offset and gain...';
            app.PixelSizeEditFieldLabel = uilabel(app.GridLayout4);
            app.PixelSizeEditFieldLabel.Text = 'Pixel Size';
            app.PixelSizeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.PixelSizeEditField.Value = 145;
            app.nmLabel_2 = uilabel(app.GridLayout4);
            app.nmLabel_2.Text = ' nm';
            app.ACsNDenoiseButton = uibutton(app.GridLayout4, 'push');
            app.ACsNDenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @ACsNDenoiseButtonPushed, true);
            app.ACsNDenoiseButton.Text = 'ACsN Denoise';
            app.ShowOriginalImageButton = uibutton(app.GridLayout4, 'push');
            app.ShowOriginalImageButton.ButtonPushedFcn = createCallbackFcn(app, @ShowOriginalImageButtonPushed, true);
            app.ShowOriginalImageButton.Text = 'Show Original Image';
            app.ButtonGroup = uibuttongroup(app.GridLayout4);
            app.ProcessSelectedOneButton = uiradiobutton(app.ButtonGroup);
            app.ProcessSelectedOneButton.Text = 'Process Selected One';
            app.ProcessSelectedOneButton.Value = true;
            app.ProcessEntireDirectoryButton = uiradiobutton(app.ButtonGroup);
            app.ProcessEntireDirectoryButton.Text = 'Process Entire Directory';
            app.SizingTab = uitab(app.TabGroup);
            app.SizingTab.Title = 'Sizing';
            app.GridLayout3 = uigridlayout(app.SizingTab);
            app.Label_3 = uilabel(app.GridLayout3);
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '';
            app.PullOutCircularMasksCheckBox = uicheckbox(app.GridLayout3);
            app.PullOutCircularMasksCheckBox.Text = '  Pull Out Circular Masks';
            app.PullOutCircularMasksCheckBox.Value = true;
            app.SetFOVsizeEditFieldLabel = uilabel(app.GridLayout3);
            app.SetFOVsizeEditFieldLabel.Text = 'Set FOV size: ';
            app.SetFOVsizeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.SetFOVsizeEditField.Value = 1024;
            app.pxsofLabel = uilabel(app.GridLayout3);
            app.pxsofLabel.Text = 'pxs of';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '1000';
            app.pixelsLabel = uilabel(app.GridLayout3);
            app.pixelsLabel.Text = 'pixels';
            app.CuttingPreviewButton = uibutton(app.GridLayout3, 'push');
            app.CuttingPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @CuttingPreviewButtonPushed, true);
            app.CuttingPreviewButton.Text = 'Cutting Preview';
            app.CutandSavetheCutImagesButton = uibutton(app.GridLayout3, 'push');
            app.CutandSavetheCutImagesButton.ButtonPushedFcn = createCallbackFcn(app, @CutandSavetheCutImagesButtonPushed, true);
            app.CutandSavetheCutImagesButton.Text = 'Cut and Save the Cut Images';
            app.RowTLEditFieldLabel = uilabel(app.GridLayout3);
            app.RowTLEditFieldLabel.Text = 'RowTL';
            app.RowTLEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RowTLEditField.ValueChangedFcn = createCallbackFcn(app, @RowTLEditFieldValueChanged, true);
            app.RowTLEditField.Value = 295;
            app.ColTLEditFieldLabel = uilabel(app.GridLayout3);
            app.ColTLEditFieldLabel.Text = 'ColTL';
            app.ColTLEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ColTLEditField.ValueChangedFcn = createCallbackFcn(app, @ColTLEditFieldValueChanged, true);
            app.ColTLEditField.Value = 262;
            app.RowTREditFieldLabel = uilabel(app.GridLayout3);
            app.RowTREditFieldLabel.Text = 'RowTR';
            app.RowTREditField = uieditfield(app.GridLayout3, 'numeric');
            app.RowTREditField.Editable = 'off';
            app.ColTREditFieldLabel = uilabel(app.GridLayout3);
            app.ColTREditFieldLabel.Text = 'ColTR';
            app.ColTREditField = uieditfield(app.GridLayout3, 'numeric');
            app.ColTREditField.Editable = 'off';
            app.RowBtmEditFieldLabel = uilabel(app.GridLayout3);
            app.RowBtmEditFieldLabel.Text = 'RowBtm';
            app.RowBtmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RowBtmEditField.Editable = 'off';
            app.ColBtmEditFieldLabel = uilabel(app.GridLayout3);
            app.ColBtmEditFieldLabel.Text = 'ColBtm';
            app.ColBtmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ColBtmEditField.Editable = 'off';
            app.PitchSizeLabel = uilabel(app.GridLayout3);
            app.PitchSizeLabel.Text = 'PitchSize';
            app.PitchSizeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PitchSizeEditField.Value = 500;
            app.pixelsLabel_2 = uilabel(app.GridLayout3);
            app.pixelsLabel_2.Text = 'pixels';
            app.SizematchingtoasquareLabel = uilabel(app.GridLayout3);
            app.SizematchingtoasquareLabel.Text = 'Size matching to a square';
            app.MatchingButton = uibutton(app.GridLayout3, 'push');
            app.MatchingButton.ButtonPushedFcn = createCallbackFcn(app, @MatchingButtonPushed, true);
            app.MatchingButton.Text = 'Matching';
            app.RECONTab = uitab(app.TabGroup);
            app.RECONTab.Title = 'RECON';
            app.GridLayout2 = uigridlayout(app.RECONTab);
            app.PSFFileButton = uibutton(app.GridLayout2, 'push');
            app.PSFFileButton.ButtonPushedFcn = createCallbackFcn(app, @PSFFileButtonPushed, true);
            app.PSFFileButton.Text = 'PSF File';
            app.FLFMFileButton = uibutton(app.GridLayout2, 'push');
            app.FLFMFileButton.ButtonPushedFcn = createCallbackFcn(app, @FLFMFileButtonPushed, true);
            app.FLFMFileButton.Text = 'FLFM File';
            app.PSFFilePathLabel = uilabel(app.GridLayout2);
            app.PSFFilePathLabel.Text = 'PSF File Path';
            app.FLFMFilePathLabel = uilabel(app.GridLayout2);
            app.FLFMFilePathLabel.Text = 'FLFM File Path';
            app.IterNumEditFieldLabel = uilabel(app.GridLayout2);
            app.IterNumEditFieldLabel.Text = 'Iter. Num.';
            app.IterNumEditField = uieditfield(app.GridLayout2, 'numeric');
            app.IterNumEditField.Limits = [1 100];
            app.IterNumEditField.RoundFractionalValues = 'on';
            app.IterNumEditField.Value = 10;
            app.RLDeconvolutionButton = uibutton(app.GridLayout2, 'push');
            app.RLDeconvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @RLDeconvolutionButtonPushed, true);
            app.RLDeconvolutionButton.Text = 'RL Deconvolution';
            app.ReconstructionFileButton = uibutton(app.GridLayout2, 'push');
            app.ReconstructionFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructionFileButtonPushed, true);
            app.ReconstructionFileButton.Text = 'Reconstruction File';
            app.ReconstructionFilePathLabel = uilabel(app.GridLayout2);
            app.ReconstructionFilePathLabel.Text = 'Reconstruction File Path';
            app.CropHeightEditFieldLabel = uilabel(app.GridLayout2);
            app.CropHeightEditFieldLabel.Enable = 'off';
            app.CropHeightEditFieldLabel.Text = 'Crop Height';
            app.CropHeightEditField = uieditfield(app.GridLayout2, 'numeric');
            app.CropHeightEditField.Enable = 'off';
            app.CropHeightEditField.Value = 1100;
            app.CropWidthEditFieldLabel = uilabel(app.GridLayout2);
            app.CropWidthEditFieldLabel.Enable = 'off';
            app.CropWidthEditFieldLabel.Text = 'Crop Width';
            app.CropWidthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.CropWidthEditField.Enable = 'off';
            app.CropWidthEditField.Value = 1100;
            app.CutShiftEditFieldLabel = uilabel(app.GridLayout2);
            app.CutShiftEditFieldLabel.Enable = 'off';
            app.CutShiftEditFieldLabel.Text = 'Cut Shift';
            app.CutShiftEditField = uieditfield(app.GridLayout2, 'numeric');
            app.CutShiftEditField.Enable = 'off';
            app.ReconstructedImageSavingOptionsLabel = uilabel(app.GridLayout2);
            app.ReconstructedImageSavingOptionsLabel.Text = 'Reconstructed Image Saving Options';
            app.FitPSFElementalDisplacementsLabel = uilabel(app.GridLayout2);
            app.FitPSFElementalDisplacementsLabel.Text = 'Fit PSF Elemental Displacements';
            app.FitPSFElementalDisplacementsButton = uibutton(app.GridLayout2, 'push');
            app.FitPSFElementalDisplacementsButton.ButtonPushedFcn = createCallbackFcn(app, @FitPSFElementalDisplacementsButtonPushed, true);
            app.FitPSFElementalDisplacementsButton.Text = 'Fit PSF Elemental Displacements';
            app.ModeDropDownLabel = uilabel(app.GridLayout2);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.GridLayout2);
            app.ModeDropDown.Items = {'Wave Optics Conventional', 'Wave Optics Full GPU', 'Hybrid with Ray Optics', 'Shifted Hybrid'};
            app.ModeDropDown.Value = 'Wave Optics Full GPU';
            app.ReplaceexistingfilesCheckBox = uicheckbox(app.GridLayout2);
            app.ReplaceexistingfilesCheckBox.Text = 'Replace existing files';
            app.DISPTab = uitab(app.TabGroup);
            app.DISPTab.Title = 'DISP';
            app.GridLayout6 = uigridlayout(app.DISPTab);
            app.CroppingPanel = uipanel(app.GridLayout6);
            app.CroppingPanel.Title = 'Cropping';
            app.GridLayout7 = uigridlayout(app.CroppingPanel);
            app.rowstart = uieditfield(app.GridLayout7, 'numeric');
            app.rowstart.Value = 801;
            app.rowend = uieditfield(app.GridLayout7, 'numeric');
            app.rowend.Value = 1480;
            app.colstart = uieditfield(app.GridLayout7, 'numeric');
            app.colstart.Value = 821;
            app.colend = uieditfield(app.GridLayout7, 'numeric');
            app.colend.Value = 1500;
            app.IntensityOffsetEditFieldLabel = uilabel(app.GridLayout6);
            app.IntensityOffsetEditFieldLabel.Text = 'Intensity Offset';
            app.IntensityOffsetEditField = uieditfield(app.GridLayout6, 'numeric');
            app.IntensityOffsetEditField.Value = 50;
            app.IntDleftwidthEditFieldLabel = uilabel(app.GridLayout6);
            app.IntDleftwidthEditFieldLabel.Text = 'IntD left width';
            app.IntDleftwidthEditField = uieditfield(app.GridLayout6, 'numeric');
            app.IntDleftwidthEditField.Value = 70;
            app.IntDrightwidthEditFieldLabel = uilabel(app.GridLayout6);
            app.IntDrightwidthEditFieldLabel.Text = 'IntD right width';
            app.IntDrightwidthEditField = uieditfield(app.GridLayout6, 'numeric');
            app.IntDrightwidthEditField.Value = 100;
            app.ScalebarPanel = uipanel(app.GridLayout6);
            app.ScalebarPanel.Title = 'Scalebar';
            app.GridLayout8 = uigridlayout(app.ScalebarPanel);
            app.LengthEditFieldLabel = uilabel(app.GridLayout8);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.GridLayout8, 'numeric');
            app.LengthEditField.Value = 1e-06;
            app.PixelsizeEditFieldLabel = uilabel(app.GridLayout8);
            app.PixelsizeEditFieldLabel.Text = 'Pixel size';
            app.barPixelsizeEditField = uieditfield(app.GridLayout8, 'numeric');
            app.barPixelsizeEditField.Value = 6.5e-08;
            app.PositionDropDownLabel = uilabel(app.GridLayout8);
            app.PositionDropDownLabel.Text = 'Position';
            app.PositionDropDown = uidropdown(app.GridLayout8);
            app.PositionDropDown.Items = {'upleft', 'upright', 'downleft', 'downright'};
            app.PositionDropDown.Value = 'upleft';
            app.IntensityGainEditFieldLabel = uilabel(app.GridLayout6);
            app.IntensityGainEditFieldLabel.Text = 'Intensity Gain';
            app.IntensityGainEditField = uieditfield(app.GridLayout6, 'numeric');
            app.IntensityGainEditField.Value = 1.2;
            app.DisplayStack3DButton = uibutton(app.GridLayout6, 'push');
            app.DisplayStack3DButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayStack3DButtonPushed, true);
            app.DisplayStack3DButton.Text = 'Display Stack 3D';
            app.SaveSelectedImageButton = uibutton(app.GridLayout6, 'push');
            app.SaveSelectedImageButton.Text = 'Save Selected Image';
            app.ProcessEntireFolderButton = uibutton(app.GridLayout6, 'push');
            app.ProcessEntireFolderButton.Text = 'Process Entire Folder';
            app.StartIndexSpinnerLabel = uilabel(app.GridLayout6);
            app.StartIndexSpinnerLabel.Text = 'Start Index';
            app.StartIndexSpinner = uispinner(app.GridLayout6);
            app.StartIndexSpinner.Limits = [1 Inf];
            app.StartIndexSpinner.Value = 20;
            app.EndIndexSpinnerLabel = uilabel(app.GridLayout6);
            app.EndIndexSpinnerLabel.Text = 'End Index';
            app.EndIndexSpinner = uispinner(app.GridLayout6);
            app.EndIndexSpinner.Limits = [1 Inf];
            app.EndIndexSpinner.Value = 80;
            app.MagentaButton = uibutton(app.GridLayout, 'push');
            app.MagentaButton.ButtonPushedFcn = createCallbackFcn(app, @MagentaButtonPushed, true);
            app.MagentaButton.Text = 'Magenta';
            app.GreenButton = uibutton(app.GridLayout, 'push');
            app.GreenButton.ButtonPushedFcn = createCallbackFcn(app, @GreenButtonPushed, true);
            app.GreenButton.Text = 'Green';
            app.CyanButton = uibutton(app.GridLayout, 'push');
            app.CyanButton.ButtonPushedFcn = createCallbackFcn(app, @CyanButtonPushed, true);
            app.CyanButton.Text = 'Cyan';
            app.InfernoButton = uibutton(app.GridLayout, 'push');
            app.InfernoButton.ButtonPushedFcn = createCallbackFcn(app, @InfernoButtonPushed, true);
            app.InfernoButton.Text = 'Inferno';
            app.JetButton = uibutton(app.GridLayout, 'push');
            app.JetButton.ButtonPushedFcn = createCallbackFcn(app, @JetButtonPushed, true);
            app.JetButton.Text = 'Jet';
            app.RainbowButton = uibutton(app.GridLayout, 'push');
            app.RainbowButton.ButtonPushedFcn = createCallbackFcn(app, @RainbowButtonPushed, true);
            app.RainbowButton.Text = 'Rainbow';
            app.GrayButton = uibutton(app.GridLayout, 'push');
            app.GrayButton.ButtonPushedFcn = createCallbackFcn(app, @GrayButtonPushed, true);
            app.GrayButton.Text = 'Gray';
            app.Image2 = uiimage(app.GridLayout);
            % Show the figure after all components are created
            app.HRFLFMDP.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataProc4HRFLFM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HRFLFMDP)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HRFLFMDP)
        end
    end
end